classdef ObjectDetectionPipelineV5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NicksYoloV5TrainingPipelineAppV5DockerLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ProjectSetUpTab                 matlab.ui.container.Tab
        ProjectFilepathEditField        matlab.ui.control.EditField
        ProjectFilepathEditFieldLabel   matlab.ui.control.Label
        SetProjectPointerButton         matlab.ui.control.Button
        DataInputTab                    matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        LocalTab                        matlab.ui.container.Tab
        ThirdPointtheApptowardstheYAMLFilePanel  matlab.ui.container.Panel
        SetYAMLFileFilepathButton       matlab.ui.control.Button
        YAMLFileFilepathEditField       matlab.ui.control.EditField
        YAMLFileFilepathEditFieldLabel  matlab.ui.control.Label
        SecondCreateInputDataFoldersPanel  matlab.ui.container.Panel
        THISWILLDELETEEXISTINGFOLDERSTOOVERWRITEMAKESUREITSCORRECTLabel  matlab.ui.control.Label
        NameofFolderEditField           matlab.ui.control.EditField
        NameofFolderEditFieldLabel      matlab.ui.control.Label
        FolderLocationEditField         matlab.ui.control.EditField
        FolderLocationEditFieldLabel    matlab.ui.control.Label
        CreateFoldersButton             matlab.ui.control.Button
        FirstPointtheApptowardstheDatayouwantPanel  matlab.ui.container.Panel
        ImagesFilepathEditField         matlab.ui.control.EditField
        ImagesFilepathEditFieldLabel    matlab.ui.control.Label
        SetInputDataDirectoryButton     matlab.ui.control.Button
        TrainModelTab                   matlab.ui.container.Tab
        ProcessorListBox                matlab.ui.control.ListBox
        ProcessorListBoxLabel           matlab.ui.control.Label
        RightMousetoCopyPasteintoDockerShellsLabel  matlab.ui.control.Label
        RECORDTHERUNATTHEENDTHISISWHEREYOURRESULTSARESTOREDLabel  matlab.ui.control.Label
        ImageSizeEditField              matlab.ui.control.NumericEditField
        ImageSizeEditFieldLabel         matlab.ui.control.Label
        BatchSizeEditField              matlab.ui.control.NumericEditField
        BatchSizeEditFieldLabel         matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        EpochsLabel                     matlab.ui.control.Label
        ModelSizeListBox                matlab.ui.control.ListBox
        ModelSizeListBoxLabel           matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        LocalTab_2                      matlab.ui.container.Tab
        TabGroup10                      matlab.ui.container.TabGroup
        ManualTab_4                     matlab.ui.container.Tab
        CodeEditField                   matlab.ui.control.EditField
        CodeEditFieldLabel              matlab.ui.control.Label
        PastethisintopowershellLabel    matlab.ui.control.Label
        GenerateCodeButton              matlab.ui.control.Button
        OpenPowershellButton            matlab.ui.control.Button
        AutomaticTab_4                  matlab.ui.container.Tab
        TRAINMODELButton                matlab.ui.control.Button
        DockerTab                       matlab.ui.container.Tab
        CreateCodeforTrainingInDockerContainerPanel  matlab.ui.container.Panel
        DONOTHAVEANYYAMLFILESWITHTHESAMENAMEINTHECONTAINERALREADYLabel  matlab.ui.control.Label
        ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel  matlab.ui.container.Panel
        DockerCreateEnterEditField      matlab.ui.control.EditField
        DockerCreateEnterEditFieldLabel  matlab.ui.control.Label
        ExecutionStep2Panel             matlab.ui.container.Panel
        DockerTrainCodeEditField        matlab.ui.control.EditField
        DockerTrainCodeEditFieldLabel   matlab.ui.control.Label
        OpenDockerContainerandTrainPanel  matlab.ui.container.Panel
        DockerTrainButton               matlab.ui.control.Button
        IfStartingPreviousContainerOptionalPanel  matlab.ui.container.Panel
        cOpenPowershellPanel            matlab.ui.container.Panel
        OpenPowershellButton_4          matlab.ui.control.Button
        aIfalreadyexistingContainerPanel  matlab.ui.container.Panel
        ContainerIDEditField            matlab.ui.control.EditField
        ContainerIDEditFieldLabel       matlab.ui.control.Label
        TrainTimerTab                   matlab.ui.container.Tab
        StopTimerButton                 matlab.ui.control.Button
        ModelwillfinishtrainingatEditField  matlab.ui.control.EditField
        ModelwillfinishtrainingatEditFieldLabel  matlab.ui.control.Label
        StartTimerButton                matlab.ui.control.Button
        SecondsLeftEditField            matlab.ui.control.NumericEditField
        SecondsLeftEditFieldLabel       matlab.ui.control.Label
        MinutesLeftEditField            matlab.ui.control.NumericEditField
        MinutesLeftEditFieldLabel       matlab.ui.control.Label
        HoursLeftEditField              matlab.ui.control.NumericEditField
        HoursLeftEditFieldLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        EpochsLeftEditField             matlab.ui.control.NumericEditField
        EpochsLeftEditFieldLabel        matlab.ui.control.Label
        EpochTrainingTimesEditField     matlab.ui.control.NumericEditField
        EpochTrainingTimesEditFieldLabel  matlab.ui.control.Label
        EpochTrainingTimeminEditField   matlab.ui.control.NumericEditField
        EpochTrainingTimeminEditFieldLabel  matlab.ui.control.Label
        TestModelTab                    matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        TestingTab                      matlab.ui.container.Tab
        TabGroup4_2                     matlab.ui.container.TabGroup
        ManualTab_3                     matlab.ui.container.Tab
        ManualTestingPanel              matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        PasteThisInEditField            matlab.ui.control.EditField
        PasteThisInEditFieldLabel       matlab.ui.control.Label
        GenerateCodeButton_2            matlab.ui.control.Button
        OpenPowershellButton_2          matlab.ui.control.Button
        AutomaticTab_3                  matlab.ui.container.Tab
        AutomaticTestingPanel           matlab.ui.container.Panel
        TESTMODELButton                 matlab.ui.control.Button
        DockerTab_7                     matlab.ui.container.Tab
        RightMousetoCopyPasteintoDockerShellsLabel_3  matlab.ui.control.Label
        ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_2  matlab.ui.container.Panel
        DockerCreateEnterEditField_3    matlab.ui.control.EditField
        DockerCreateEnterEditField_3Label  matlab.ui.control.Label
        ExecutionStep2Panel_3           matlab.ui.container.Panel
        DockerTestCodeEditField_2       matlab.ui.control.EditField
        DockerTestCodeEditField_2Label  matlab.ui.control.Label
        OpenDockerContainerandTrainPanel_3  matlab.ui.container.Panel
        OpenPowershellButton_7          matlab.ui.control.Button
        DockerTestButton_2              matlab.ui.control.Button
        aIfalreadyexistingContainerPanel_3  matlab.ui.container.Panel
        ContainerIDEditField_3          matlab.ui.control.EditField
        ContainerIDEditField_3Label     matlab.ui.control.Label
        YAMLFilepathEditField           matlab.ui.control.EditField
        YAMLFilepathEditFieldLabel      matlab.ui.control.Label
        ImageSizeEditField_2            matlab.ui.control.NumericEditField
        ImageSizeEditField_2Label       matlab.ui.control.Label
        TestDataFilepathEditField       matlab.ui.control.EditField
        TestDataFilepathEditFieldLabel  matlab.ui.control.Label
        ModelWeightsFilepathEditField   matlab.ui.control.EditField
        ModelWeightsFilepathLabel       matlab.ui.control.Label
        GetYAMLFilepathButton           matlab.ui.control.Button
        GetTestDataFilepathButton       matlab.ui.control.Button
        GetWeightsFilepathButton        matlab.ui.control.Button
        AnalysisTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        DetectionMetricsTab             matlab.ui.container.Tab
        LoadTableButton                 matlab.ui.control.Button
        ExportMetricstoTxtButton        matlab.ui.control.Button
        RemoveEntryPanel                matlab.ui.container.Panel
        RemoveEntryButton               matlab.ui.control.Button
        RowEditField                    matlab.ui.control.NumericEditField
        RowEditFieldLabel               matlab.ui.control.Label
        ClearTableButton                matlab.ui.control.Button
        GenerateMetricsButton           matlab.ui.control.Button
        TotalObjectsPresentEditField    matlab.ui.control.NumericEditField
        TotalObjectsPresentEditFieldLabel  matlab.ui.control.Label
        AddEntryPanel                   matlab.ui.container.Panel
        AddEntryButton                  matlab.ui.control.Button
        FalseNegativesEditField         matlab.ui.control.NumericEditField
        FalseNegativesEditFieldLabel    matlab.ui.control.Label
        FalsePositivesEditField         matlab.ui.control.NumericEditField
        FalsePositivesEditFieldLabel    matlab.ui.control.Label
        TruePositivesEditField          matlab.ui.control.NumericEditField
        TruePositivesEditFieldLabel     matlab.ui.control.Label
        DetectionsEditField             matlab.ui.control.NumericEditField
        DetectionsEditFieldLabel        matlab.ui.control.Label
        ExportTableButton               matlab.ui.control.Button
        DetectionRateEditField          matlab.ui.control.NumericEditField
        DetectionRateEditFieldLabel     matlab.ui.control.Label
        TotalFalsePositivesEditField    matlab.ui.control.NumericEditField
        TotalFalsePositivesEditFieldLabel  matlab.ui.control.Label
        TotalTruePositivesEditField     matlab.ui.control.NumericEditField
        TotalTruePositivesEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ConfidenceLevelsTab             matlab.ui.container.Tab
        FalsePositivesPanel             matlab.ui.container.Panel
        LoadFPTableButton               matlab.ui.control.Button
        ClearFPTableButton              matlab.ui.control.Button
        SaveFPTableButton               matlab.ui.control.Button
        TruePositivesPanel              matlab.ui.container.Panel
        LoadTPTableButton               matlab.ui.control.Button
        ClearTPTableButton              matlab.ui.control.Button
        SaveTPTableButton               matlab.ui.control.Button
        RemoveCLEntryPanel              matlab.ui.container.Panel
        DeleteFPButton                  matlab.ui.control.Button
        DeleteTPButton                  matlab.ui.control.Button
        RowEditField_2                  matlab.ui.control.NumericEditField
        RowEditField_2Label             matlab.ui.control.Label
        AddCLEntryPanel                 matlab.ui.container.Panel
        AddEntryButton_2                matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FalsePositiveButton             matlab.ui.control.RadioButton
        TruePositiveButton              matlab.ui.control.RadioButton
        CLEditField                     matlab.ui.control.NumericEditField
        CLEditFieldLabel                matlab.ui.control.Label
        FalsePositivesRemainingEditField  matlab.ui.control.NumericEditField
        FalsePositivesRemainingEditFieldLabel  matlab.ui.control.Label
        TruePositivesRemainingEditField  matlab.ui.control.NumericEditField
        TruePositivesRemainingEditFieldLabel  matlab.ui.control.Label
        CLThresholdEditField            matlab.ui.control.NumericEditField
        CLThresholdLabel                matlab.ui.control.Label
        CLThresholdSlider               matlab.ui.control.Slider
        CLThresholdSliderLabel          matlab.ui.control.Label
        UITable2_2                      matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        PlottingTab                     matlab.ui.container.Tab
        ThisenablesTheslidertotherightmovetoupdateplotLabel  matlab.ui.control.Label
        CLThresholdSlider_2             matlab.ui.control.Slider
        CLThresholdSlider_2Label        matlab.ui.control.Label
        SliderOnPlotButtonGroup         matlab.ui.container.ButtonGroup
        NoButton                        matlab.ui.control.RadioButton
        YesButton                       matlab.ui.control.RadioButton
        ThenPlotPanel                   matlab.ui.container.Panel
        GenerateDetectionNumberPlotButton  matlab.ui.control.Button
        GenerateDetectionPercentagePlotButton  matlab.ui.control.Button
        CLDistributionPlotButton        matlab.ui.control.Button
        FirstCreatePanel                matlab.ui.container.Panel
        CreateDistributionsButton       matlab.ui.control.Button
        OpenCurrentPlottoFigureButton   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ExportModelTab                  matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        LocalTab_4                      matlab.ui.container.Tab
        ImageSizeEditField_3            matlab.ui.control.NumericEditField
        ImageSizeEditField_3Label       matlab.ui.control.Label
        WeightsFilepathEditField        matlab.ui.control.EditField
        WeightsFilepathEditFieldLabel   matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        ManualTab                       matlab.ui.container.Tab
        CodePasteInEditField            matlab.ui.control.EditField
        CodePasteInEditFieldLabel       matlab.ui.control.Label
        GenerateCodeButton_3            matlab.ui.control.Button
        OpenPowershellButton_3          matlab.ui.control.Button
        AutomaticTab                    matlab.ui.container.Tab
        ExportModelButton               matlab.ui.control.Button
        DockerTab_8                     matlab.ui.container.Tab
        RightMousetoCopyPasteintoDockerShellsLabel_5  matlab.ui.control.Label
        ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_3  matlab.ui.container.Panel
        DockerCreateEnterEditField_5    matlab.ui.control.EditField
        DockerCreateEnterEditField_5Label  matlab.ui.control.Label
        ExecutionStep2Panel_5           matlab.ui.container.Panel
        DockerExportCodeEditField       matlab.ui.control.EditField
        DockerExportCodeEditFieldLabel  matlab.ui.control.Label
        OpenDockerContainerandExportPanel  matlab.ui.container.Panel
        OpenPowershellButton_9          matlab.ui.control.Button
        DockerExportButton              matlab.ui.control.Button
        aIfalreadyexistingContainerPanel_5  matlab.ui.container.Panel
        ContainerIDEditField_5          matlab.ui.control.EditField
        ContainerIDEditField_5Label     matlab.ui.control.Label
        GetWeightsButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        YamlFilepathValue % Description
        t % Timer
        WeightsFilepathValue % Description
        StopTimer % Description
        CLDistributionTable % Description
        CurrentPlot % Description
        CurrentLegend % Description
        CLThreshHandle % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateCodeButton
        function GenerateCodeButtonPushed(app, event)
            %% For Manual Mode, generates the code that is presented
            % This section creates the size of the model based on the input
            % of the user. It translates the option chosen into the
            % formatting the Python function requires. (train.py)
            if app.ModelSizeListBox.Value == "Nano"
                modelsize = "yolov5n.pt";
            elseif app.ModelSizeListBox.Value == "Small"
                modelsize = "yolov5s.pt";
            elseif app.ModelSizeListBox.Value == "Medium"
                modelsize = "yolov5m.pt";
            elseif app.ModelSizeListBox.Value == "Large"
                modelsize = "yolov5l.pt";
            elseif app.ModelSizeListBox.Value == "Extra Large"
                modelsize = "yolov5x.pt";
            end

            % This section relates to translating the processing method
            % options into the formatting the python function requires.
            % (train.py)
            if app.ProcessorListBox.Value == "GPU"
                processorSize = "0";
            else
                processorSize = "cpu";
            end


            % This section splits up the YAML filepath to find the relative
            % path from the start of the project, as that is the working
            % directory of the python training function (train.py).
            newStr = split(app.YAMLFileFilepathEditField.Value, "\");
            Yamlfilelocation = newStr(end);
            varImageSZ = app.ImageSizeEditField.Value; % User inputted, determines the resolution the images will be converted to. 

            % Generates the code and displays it to the user in an easy to
            % copy field for pasting into powershell. This is the format it
            % requires.
            textGenerate = "cd " + string(app.ProjectFilepathEditField.Value) + "\; python train.py --data " + Yamlfilelocation + " --epochs " + string(app.EpochsEditField.Value) + ...
                " --weights " + modelsize + " --batch-size " + string(app.BatchSizeEditField.Value) + " --img " + varImageSZ + " --device " + string(processorSize) + "";
            app.CodeEditField.Value = textGenerate; % Provides generated line to user
            
        end

        % Button pushed function: SetInputDataDirectoryButton
        function SetInputDataDirectoryButtonPushed(app, event)
            %% This function sets the input directory for the initial training/validation images
            t = []; % Initialize temp variable
            if ~(app.ImagesFilepathEditField.Value) % If it already has a value
                t = app.ImagesFilepathEditField.Value; % Keep stored in temp variable
            end
            location = string((app.ProjectFilepathEditField.Value))+ "\"; % Fixes the string
            TrainingDirectory = uigetdir(location); % UI window to get the directory where the training images are being stored
            TrainingDirectory2 = string(TrainingDirectory) % Temp variable to check if UI window was canceled out of or not
            if ~(TrainingDirectory2 == "0")
                app.ImagesFilepathEditField.Value = string(TrainingDirectory); % Displays filepath in Training Images Filepath text field back in App
                %app.TrainingTestingImagesFilepathEditField.Value = string(TrainingDirectory); % Displays filepath in Training Images Filepath text field back in App

            elseif (TrainingDirectory2 == "0") && ~(isempty(t)) % If the UI is backed out of instead of selecting a new value, overwrite the values with the stored temp ones.
                app.ImagesFilepathEditField.Value = t;
                %app.TrainingTestingImagesFilepathEditField.Value = t;

            end

           
        end

        % Button pushed function: GetYAMLFilepathButton, 
        % ...and 1 other component
        function SetYAMLFileFilepathButtonPushed(app, event)
            %% This function sets the filepath to the YAML file, which controls finding the various directories for training, validation and test
            t = []; % Initializes temp variable
            t2 = []; % Initializes temp variable
            t3 = []; % Initializes temp variable
            if ~(app.YAMLFileFilepathEditField.Value) % If it already has a value
                t = app.YAMLFileFilepathEditField.Value; % Copy the current values
                t2 =  app.YamlFilepathValue.Value; % Copy the current values
                t3 = app.YAMLFilepathEditField.Value; % Copy the current values
            end
            location = string((app.ProjectFilepathEditField.Value))+ "\data\"; % Sets the initial directory, where all YAML files are kept
            [YamlFile, YamlFilepath] = uigetfile('*.*', 'Select a YAML file', location); % UI window for the user to select the correct YAML file.
            EmptyTest = string(YamlFilepath); % Temp variable to check if UI window was canceled out of or not
            
            if ~(EmptyTest == "0") % If UI is not canceled out of
                app.YamlFilepathValue.Value = string(YamlFilepath); % Sets Property with YAML filepath before the file only for use outside of this function
                app.YAMLFileFilepathEditField.Value = string(YamlFilepath) + string(YamlFile); % Initial UI function splits the filepath, this places them back together
                app.YAMLFilepathEditField.Value = string(YamlFilepath) + string(YamlFile); % Sets YAML filepath field on Testing model App page so they match
            elseif (EmptyTest == "0") && ~(isempty(t)) % if UI is canceled out of and wasnt empty previously
                app.YamlFilepathValue.Value = t2; % Sets Property with YAML filepath before the file only for use outside of this function
                app.YAMLFileFilepathEditField.Value = t; % Initial UI function splits the filepath, this places them back together
                app.YAMLFilepathEditField.Value = t3; % Sets YAML filepath field on Testing model App page so they match
            end


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %% This function calculates how long training a model will take based on the remaining epochs left in a training, and how long a single epoch takes (on average).

            % The minutes are converted to seconds and added to the seconds
            % entered, then multiplied by the epochs left. This is then
            % displayed in an HH-MM-SS format. Lastly, estimates when a
            % model will be finished training (UTC)
            TimeleftinMin = app.EpochsLeftEditField.Value * (((app.EpochTrainingTimeminEditField.Value * 60) + (app.EpochTrainingTimesEditField.Value)) / 60); % Converts Hours to seconds, minutes to seconds, and adds to seconds to create a single time value
            app.HoursLeftEditField.Value = floor(TimeleftinMin/60); % Turns the amount of seconds left into hours
            app.MinutesLeftEditField.Value = floor(TimeleftinMin - app.HoursLeftEditField.Value * 60); % removing those hours worth of seconds, take seconds left and convert them to minutes.
            app.SecondsLeftEditField.Value = floor(TimeleftinMin*60 - (app.HoursLeftEditField.Value*60*60) - (app.MinutesLeftEditField.Value*60)); % Removing those hours and minutes worth of seconds, take the seconds left.
            app.ModelwillfinishtrainingatEditField.Value = string(datetime("now") + hours(app.HoursLeftEditField.Value) + minutes(app.MinutesLeftEditField.Value) + seconds(app.SecondsLeftEditField.Value)); % Calculates an estimate of when the model will finish training in UTC.
        end

        % Button pushed function: OpenPowershellButton, 
        % ...and 3 other components
        function OpenPowershellButtonPushed(app, event)
            %% This function opens Powershell
            !powershell &
            
        end

        % Button pushed function: StartTimerButton
        function StartTimerButtonPushed(app, event)
            %% This function takes the calculated amount of time to train a model and turns it into a timer, beginning to count down.
            
            
            app.StopTimer.Value = false; % Sets Stop Timer value to false so timer can begin
            
            TimeleftinSec = app.HoursLeftEditField.Value*60*60 + app.MinutesLeftEditField.Value*60 + app.SecondsLeftEditField.Value; % Converts time calculated into a single value of seconds
            for s = TimeleftinSec:-1:1 % For loop for counting down in seconds
                app.HoursLeftEditField.Value = floor(s / 60 / 60); % Calculates hours left from seconds left
                app.MinutesLeftEditField.Value = floor(s / 60 - app.HoursLeftEditField.Value * 60); % Calculates minutes left from seconds and removes hours
                app.SecondsLeftEditField.Value = floor(s - (app.HoursLeftEditField.Value*60*60 + app.MinutesLeftEditField.Value * 60)); % Calculates seconds left removing both hours and minutes
                pause(1); % Actual timer part
                if app.StopTimer.Value % Escape condtion if Stop Timer button is pressed to allow it to end
                    break
                end
                s = s-1; % Counter variable
            end

            % Sets last values for the timer running out
            app.HoursLeftEditField.Value = 0;
            app.MinutesLeftEditField.Value = 0;
            app.SecondsLeftEditField.Value = 0;
            app.ModelwillfinishtrainingatEditField.Value = string(datetime("now"));

        end

        % Button pushed function: OpenPowershellButton_2
        function OpenPowershellButton_2Pushed(app, event)
            %% This function opens Powershell

            !powershell &
            
        end

        % Button pushed function: GenerateCodeButton_2
        function GenerateCodeButton_2Pushed(app, event)
            %% This function generates the code for testing a model that is displayed back to the user to be modified and pasted into Powershell.
            
            % This splits the YAML filepath to find the relative route
            % from the beginning of the project directory, not the absolute
            % route.
            newStr = split(app.YAMLFileFilepathEditField.Value, "\");
            Yamlfilelocation = newStr(end); % Grabs just the name of the YAML file.
            weights = ""; % Intialize Variable
            TestDataFileLocation = ""; % Intialize Variable

            % This splits the test data filepath for the same reason as
            % above
            newStr2 = split(app.TestDataFilepathEditField.Value,"\");
            if ~(isempty(newStr2{1})) % Protection against missing the test data filepath, only runs if its populated. 
                TestDataFileLocation = newStr2(end-1:end); % Finds the relative route inside the project, if folders are changed this will break. 
                TestDataFileLocation2 = ""; % Initializes the Testdata working value, overall not important
                for s = 1:size(TestDataFileLocation) % This for loop rebuilds the test data filepath in the correct format from its current split state
                    if s == 1
                        TestDataFileLocation2 = TestDataFileLocation2 + string(TestDataFileLocation(s)); % If its the first one, simply take the first directory
                    else
                        TestDataFileLocation2 = TestDataFileLocation2 + "\" + string(TestDataFileLocation(s)); % Add directories in order to the working value
                    end
                    s = s + 1; % Counter Variable
    
                end
                TestDataFileLocation = TestDataFileLocation2; %replaces final value with new filepath string
            else
                alert = warndlg("Missing Test Image Filepath, will not work", "WARNING");
            end

            % This splits the weights filepath for the same reason as above
            newStr3 = split(app.ModelWeightsFilepathEditField.Value  , "\"); % Splits filepath by slash
            if ~(isempty(newStr3{1}))
                weights = newStr3(end-4:end); % This is internal to the structure of the project, should folders be moved this will need to be updated
                weights2 = ""; % Initializes interim weights variable
                for s = 1:size(weights) % For loop for every string inside the split filepath
                    if s == 1
                        weights2 = weights2 + string(weights(s)); % If first value, simply take base route
                    else
                        weights2 = weights2 + "\" + string(weights(s)); % Every value after requires the slash
                    end
                    s = s + 1; % Counter Variable
    
                end
                weights = weights2; % Final Variable takes on interim variable's value
            else
                alert = warndlg("Missing Weights Filepath, will not work", "WARNING");
            end
            % Generates the code necessary to test the model to be
            % displayed back to the user.
            textGenerate = "cd "+string(app.ProjectFilepathEditField.Value)+"\; python detect.py --weights " + weights + " --data " + Yamlfilelocation + " --source " +  TestDataFileLocation + " --img " + string(app.ImageSizeEditField_2.Value);
            app.PasteThisInEditField.Value = textGenerate; % Displays final code to User in app.
        end

        % Button pushed function: GetWeightsButton, 
        % ...and 1 other component
        function GetWeightsFilepathButtonPushed(app, event)
            %% This function finds the filepath of the weights of a trained model based on User input

            t = []; % Initializes temp Variable
            t2 = []; % Initializes temp Variable
            t3 = []; % Initializes temp Variable
            if ~(app.ModelWeightsFilepathEditField.Value) % If the value is not empty
                t = app.ModelWeightsFilepathEditField.Value; % Copy weights value into temp variable
                t2 = app.WeightsFilepathValue.Value; % Copy weights value into temp variable
                t3 = app.WeightsFilepathEditField.Value; % Copy weights value into temp variable
            end

            location = string((app.ProjectFilepathEditField.Value))+ "\runs\train"; % Creates initial filepath for the UI window
            [PTFile, PTFilepath] = uigetfile('*.*', 'Select most recent best.pt file', location); % Opens UI window for User to select weights from a training run
            emptyTest = string(PTFile); % Create testing variable

            if ~(emptyTest== "0") % If not canceled out
                app.ModelWeightsFilepathEditField.Value = string(PTFilepath) + string(PTFile); % Displays the filepath back to the User
                app.WeightsFilepathValue.Value = string(PTFilepath); % Internal property for later use in functions
                app.WeightsFilepathEditField.Value = string(PTFilepath); % Displays the filepath back to the User from other Weights displays in the app.

            elseif (emptyTest == "0") && ~(isempty(t)) % If canceled out and had a previous value
                app.ModelWeightsFilepathEditField.Value = t; % Displays the filepath back to the User
                app.WeightsFilepathValue.Value = t2; % Internal property for later use in functions
                app.WeightsFilepathEditField.Value = t3; % Displays the filepath back to the User from other Weights displays in the app.
            end
           
            
        end

        % Button pushed function: GetTestDataFilepathButton
        function GetTestDataFilepathButtonPushed(app, event)
            %% This Function gets the filepath of the test data for testing a model. It is the directory where the testing images are kept.
            t = []; % Initializes temp Variable
           
            if ~(app.TestDataFilepathEditField.Value) % If the value is not empty
                t = app.TestDataFilepathEditField.Value; % Copy value into temp variables
            end

            location = string((app.ProjectFilepathEditField.Value))+ "\data\"; % Creates initial filepath for directing the UI window
            TestingDirectory = uigetdir(location); % UI window for the user to select the folder containing the testing images
            emptyTest = string(TestingDirectory); % Create testing variable for if UI window is canceled out.

            if ~(emptyTest== "0") % If not canceled out
                app.TestDataFilepathEditField.Value = string(TestingDirectory); % displays this filepath back to the User.

            elseif (emptyTest == "0") && ~(isempty(t)) % If canceled out and had a previous value
                app.TestDataFilepathEditField.Value = t; % displays this filepath back to the User.
            end


        end

        % Button pushed function: OpenPowershellButton_3
        function OpenPowershellButton_3Pushed(app, event)
            %% This function opens powershell
            !powershell &

        end

        % Button pushed function: GenerateCodeButton_3
        function GenerateCodeButton_3Pushed(app, event)
            %% This function generates the code for exporting a model to tflite for use with the UAV

            % This section finds the weights filepath
            if ~(app.ModelWeightsFilepathEditField.Value == "0") && ~(isempty(app.ModelWeightsFilepathEditField.Value)) % Checks to see if the Weights filepath isnt empty or unintialized. 

                newStr3 = split(app.ModelWeightsFilepathEditField.Value , "\"); %Splits the weights filepath into sections based on the slash
                weights = newStr3(end-4:end); % Finds the weights filepath based on the base project directory, if folders are moved this will need to be updated
                weights2 = ""; % Initializes the interim temp variable, overall not important. 
                for s = 1:size(weights) % For loop for length of how many sections are in the split string
                    if s == 1
                        weights2 = weights2 + string(weights(s)); % For the first section, keep normal.
                    else
                        weights2 = weights2 + "\" + string(weights(s)); % For every section following, add the slashes for formatting
                    end
                    s = s + 1; % Counter variable
    
                end
                weights = weights2; % Transfer weights filepath value from iterim variable to final variable
            else
                alert = warndlg("WEIGHTS FILEPATH MISSING", "INVALID ENTRY"); % Alert user that this function will fail without having the filepath set
            end
            
            % Code in required format for python file (export.py)
            textGenerate = "cd "+app.ProjectFilepathEditField.Value+"\; python export.py --img " + string(app.ImageSizeEditField_3.Value) + " --weights " + weights + " --include tflite";
            app.CodePasteInEditField.Value = textGenerate; % Diplays code to User.
        end

        % Button pushed function: StopTimerButton
        function StopTimerButtonPushed(app, event)
            %% This function sets the interal app property StopTimer to true, which will end the timer
            app.StopTimer.Value = true;
        end

        % Button pushed function: AddEntryButton
        function AddEntryButtonPushed(app, event)
            %% This function adds entries to the detection metrics table
            
            t = app.UITable.Data; % Creates temp variable from current
            entryNum = height(app.UITable.Data); % Finds entry num so it can incrementally grow
            nr = {entryNum+1 app.DetectionsEditField.Value app.TruePositivesEditField.Value app.FalsePositivesEditField.Value app.FalseNegativesEditField.Value}; % Creates new entry values
            app.UITable.Data = [t;nr]; % updates table with new row and values of entry.
        end

        % Button pushed function: RemoveEntryButton
        function RemoveEntryButtonPushed(app, event)
            %% This function removes entries to the detection metrics table
            
            t = app.UITable.Data; % Copy of current table
            numToRemove = app.RowEditField.Value; % Row number to remove from User input
            if ~(isempty(t)) && ~(numToRemove > height(t)) % If current table is not empty and entry is not out of the bounds of the table
                t(numToRemove,:) = []; % Set that row to null
                app.UITable.Data = t; % update table with new table
            end
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            %% This function clears the table and completely empties it.
            
            app.UITable.Data = [];
        end

        % Button pushed function: GenerateMetricsButton
        function GenerateMetricsButtonPushed(app, event)
            %% This function generates the various sums and metrics based off of the table.
            
            t = app.UITable.Data; % Working table creation
           
            checkEmpty = isempty(t); % Checks whether the working table is empty
            if ~checkEmpty % If there are values
                TotRealPos = sum([t{:,3}]); % Sum all true positives together to get the total
                TotFalsPos = sum([t{:,4}]); % Sum all false positives together to get the total
                TotObjects = TotRealPos + sum([t{:,5}]); % Sum all objects together for generation of the detection rate
                DetRate = TotRealPos/TotObjects; % create the detection rate
    
                app.TotalTruePositivesEditField.Value = TotRealPos; % Display this value to the User
                app.TotalFalsePositivesEditField.Value = TotFalsPos; % Display this value to the User
                app.TotalObjectsPresentEditField.Value = TotObjects; % Display this value to the User
                app.DetectionRateEditField.Value = DetRate; % Display this value to the User
            end
        end

        % Button pushed function: AddEntryButton_2
        function AddEntryButton_2Pushed(app, event)
            %% This function adds an entry to the true and false positive CL tables
            
            if app.TruePositiveButton.Value == 1 % Checks that the value entered is a true positive
                t = app.UITable2.Data; % creates working table
                entryNum = height(t); % finds current rows of table
                nr = {entryNum+1 app.CLEditField.Value}; % creates new value with new row identifier
                app.UITable2.Data = [t;nr]; % updates table
            else % if value entered is for the false positives
                t = app.UITable2_2.Data; % creates working table
                entryNum = height(t); % finds current rows of table
                nr = {entryNum+1 app.CLEditField.Value}; % creates new value with new row identifier
                app.UITable2_2.Data = [t;nr]; % updates table
            end
        end

        % Button pushed function: ClearTPTableButton
        function ClearTPTableButtonPushed(app, event)
            %% This function clears the true positive table to null
            
            app.UITable2.Data = [];
        end

        % Button pushed function: ClearFPTableButton
        function ClearFPTableButtonPushed(app, event)
            %% This function clears the false positive table to null
            
            app.UITable2_2.Data = [];
        end

        % Value changing function: CLThresholdSlider
        function CLThresholdSliderValueChanging(app, event)
            %% This function updates the amount of True and false positives remaining displayed to the User and activates upon the slider being manipulated by the User. 
            
            changingValue = event.Value; % The current value the slider is set to.
            app.CLThresholdEditField.Value = round(changingValue,2); % Rounds the values for the numerical display as default is 8 sig figures.
            if ~isempty(app.UITable2.Data) && ~isempty(app.UITable2_2.Data) % Checks whether tables have entries to calculate, if both tables have entries
                t = app.UITable2.Data(:,2); % creates working true positive table.
                t2 = app.UITable2_2.Data(:,2); % creates working false positive table.
                workingTotPos = 0; % Initializes working variable
                workingTotFal = 0; % Initializes working variable
                for i = 1:1:length(t) %for the length of the entire TP tables entries
                    if t{i} >= changingValue
                        workingTotPos = workingTotPos + 1; % If the value of the entry is greater than the current slider value, add one to the total for that entry
                    end
                end
                for i = 1:1:length(t2) % for the length of the entire FP tables entries
                    if t2{i} >= changingValue
                        workingTotFal = workingTotFal + 1; % If the value of the entry is greater than the current slider value, add one to the total for that entry
                    end
                end
                app.FalsePositivesRemainingEditField.Value = workingTotFal; % Display these values back to the User.
                app.TruePositivesRemainingEditField.Value = workingTotPos; % Display these values back to the User.
            elseif ~isempty(app.UITable2.Data) && isempty(app.UITable2_2.Data) % Checks whether tables have entries to calculate, if TP table has entries but FP does not 
                t = app.UITable2.Data(:,2); % creates working true positive table.
                workingTotPos = 0; % Initializes working variable
                for i = 1:1:length(t) %for the length of the entire TP tables entries
                    if t{i} >= changingValue
                        workingTotPos = workingTotPos + 1; % If the value of the entry is greater than the current slider value, add one to the total for that entry
                    end
                end
                
                app.TruePositivesRemainingEditField.Value = workingTotPos; % Display these values back to the User
            elseif isempty(app.UITable2.Data) && ~isempty(app.UITable2_2.Data)  % Checks whether tables have entries to calculate, if FP table has entries but TP does not
                t2 = app.UITable2_2.Data(:,2); % creates working false positive table.
                workingTotFal = 0; % Initializes working variable
                
                for i = 1:1:length(t2) % for the length of the entire FP tables entries
                    if t2{i} >= changingValue
                        workingTotFal = workingTotFal + 1; % If the value of the entry is greater than the current slider value, add one to the total for that entry
                    end
                end
                app.FalsePositivesRemainingEditField.Value = workingTotFal; % Display these values back to the User
            end

        end

        % Value changed function: CLThresholdEditField
        function CLThresholdEditFieldValueChanged(app, event)
            %% This function updates the CL threshold slider based off of the value inputted into the field.
            
            value = app.CLThresholdEditField.Value; % User inputted value
            app.CLThresholdSlider.Value = value; % Updates CL threshold slider
            t = app.UITable2.Data(:,2); % Working TP CL table
            t2 = app.UITable2_2.Data(:,2); % Working FP CL table
            workingTotPos = 0; % Initialized Total TP variable
            workingTotFal = 0; % Initialized Total FP variable
            for i = 1:1:length(t) % For the length of the TP CL table
                if t{i} >= value 
                    workingTotPos = workingTotPos + 1; % If the value of the entry is greater than the current slider value, add one to the total for that entry
                end
            end
            for i = 1:1:length(t2)  % For the length of the FP CL table
                if t2{i} >= value
                    workingTotFal = workingTotFal + 1; % If the value of the entry is greater than the current slider value, add one to the total for that entry
                end
            end
            app.FalsePositivesRemainingEditField.Value = workingTotFal; % Display these values back to the User
            app.TruePositivesRemainingEditField.Value = workingTotPos; % Display these values back to the User
        end

        % Button pushed function: DeleteTPButton
        function DeleteTPButtonPushed(app, event)
            %% This function deletes a row entry from the TP CL table
            
            t = app.UITable2.Data; % Creates working table
            numToRemove = app.RowEditField_2.Value; % Variable for row to be removed
            if ~(isempty(t)) && ~(numToRemove > height(t)) % if table is not empty or row to remove not outside the index range
                t(numToRemove,:) = []; % set row to null
                app.UITable2.Data = t; % update table
            end
        end

        % Button pushed function: DeleteFPButton
        function DeleteFPButtonPushed(app, event)
            %% This function deletes a row entry from the TP CL table
            
            t = app.UITable2_2.Data; % Creates working table
            numToRemove = app.RowEditField_2.Value; % Variable for row to be removed
            if ~(isempty(t)) && ~(numToRemove > height(t)) % if table is not empty or row to remove not outside the index range
                t(numToRemove,:) = []; % set row to null
                app.UITable2_2.Data = t; % update table
            end
        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            %% This function takes the current detection metrics table and creates a mat table for easy exporting and saving
            
            t = app.UITable.Data;
            save("DetectionMetrics.mat","t", '-mat')
            alert = warndlg("Saved to DetectionMetrics.mat in Workspace", "SAVED");
        end

        % Button pushed function: SaveTPTableButton
        function SaveTPTableButtonPushed(app, event)
            %% This function takes the current TP CL table and creates a mat table for easy exporting saving
            
            t = app.UITable2.Data;
            save("TruePositiveCLs.mat","t", '-mat')
            alert = warndlg("Saved to TruePositivesCLs.mat in Workspace", "SAVED");
        end

        % Button pushed function: SaveFPTableButton
        function SaveFPTableButtonPushed(app, event)
            %% This function takes the current TP CL table and creates a mat table for easy exporting saving
            
            t = app.UITable2_2.Data;
            save("FalsePositiveCLs.mat","t", '-mat')
            alert = warndlg("Saved to FalsePositivesCLs.mat in Workspace", "SAVED");
        end

        % Button pushed function: GenerateDetectionNumberPlotButton
        function GenerateDetectionNumberPlotButtonPushed(app, event)
           %% This function takes the columns of the distribution table relating to the number of detection remaining at certain CLs and plots it.
            
           xlabel(app.UIAxes,'Confidence Levels')
           ylabel(app.UIAxes,'Number of Detections')
           title(app.UIAxes,'Detections At or Above a CL by Number')
           plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,6}) % Plots detections (TP) remaining(y) at CL bins (x). Ex. 30 Detections of 40 total are above a .8 CL threshold
           hold(app.UIAxes,"on")
           plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,7}) % Plots detections (FP) remaining (Y) at CL Bins (X). Ex. 30 Detections of 40 total are above a .8 CL threshold
           hold(app.UIAxes,"off")
           legend(app.UIAxes,'TP > CL','FP > CL',"Location","northwest")
           app.CurrentPlot = "DetectionNumber"; % This property used to identify specific plot type later on
        end

        % Button pushed function: CLDistributionPlotButton
        function CLDistributionPlotButtonPushed(app, event)
           %% This function takes the columns of the distribution table relating to the CL bins and plots them.
            
           xlabel(app.UIAxes,'Confidence of Prediction')
           ylabel(app.UIAxes,'Frequency Percentage')
           title(app.UIAxes,'Confidence Level Distribution Across All Images')
           plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,4}) % Plots number of TP entries at a certain 0.05 CL bin (x). Ex. 30% of all TP detections are between 0.6 and 0.65 CLs
           hold(app.UIAxes,"on")
           plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,5}) % Plots number of FP entries at a certain 0.05 CL bin (x). Ex. 30% of all FP detections are between 0.6 and 0.65 CLs
           hold(app.UIAxes,"off")
           lgd = legend(app.UIAxes,'True Positives','False Positives',"Location","northwest");
           app.CurrentLegend = lgd;
           app.CurrentPlot = "CLDistribution"; % This property used to identify specific plot type later on
           

        end

        % Button pushed function: CreateDistributionsButton
        function CreateDistributionsButtonPushed(app, event)
            %% This function creates the distribution table used for calculations and plotting Analysis. 
            %% This section initializes variables needed.
            % Basic structure is Possible Bins
            % (0.05), TruePosBinsCount, FalsPosBinsCount, TruePosBinsCountNorm, FalsPosBinsCountNorm,
            % FindRemainingColumn, FindRemainingColumnFals, RemainingNorm
            % (%),RemainingNormFals (%)

            % Structure of the function is:
            % First - Initialization
            % Second - If else for in case tables are missing or unbalanced
            % Third (inside) - Generate Columns
            % Fourth - Everything formatted and finalized
            
            Distro = [0;0.05;0.1;0.15;0.20;0.25;0.3;0.35;0.4;0.45;0.5;0.55;0.6;0.65;0.7;0.75;0.8;0.85;0.9;0.95]; % Simplifies calculations by creating Bins for possible CLs
            TruePosBinsCount = zeros(20,1); % Initialize True Positive total counter. If there are 3 TP CLs at .55-.6, at .55 it will hold the integer 3.
            FalsPosBinsCount = zeros(20,1); % Initialize False Positive total counter. If there are 3 TP CLs at .55-.6, at .55 it will hold the integer 3.
            TruePosBinsCountNorm = zeros(20,1); % Initialize True Positive normalized counter. If there are 3 TP CLs at .55-.6, and 3 more at .8, at .55 it will hold the float .5.
            FalsPosBinsCountNorm = zeros(20,1); % Initialize False Positive normalized counter. If there are 3 TP CLs at .55-.6, and 3 more at .8, at .55 it will hold the float .5
            FindRemainingColumn = zeros(20,1); % Initializes Remaining TP counter. Finds how many of the total of TPs remain if everything below a CL was disregarded. Final looks like 35,35,35,35,35,35,35,35,30,25,20,15,10,0 or something similar.
            FindRemainingColumnFals = zeros(20,1); % Initializes Remaining FP counter. Finds how many of the total of FPs remain if everything below a CL was disregarded. Final looks like 35,35,35,35,35,35,35,35,30,25,20,15,10,0 or something similar.
            RemainingNorm = zeros(20,1); % Initializes Remaining TP counter normalized. Finds distribution of TPs remaining if everything below a CL threshold was disregarded and then normalized to a percentage of the total.
            RemainingNormFals = zeros(20,1); % Initializes Remaining FP counter normalized. Finds distribution of FPs remaining if everything below a CL threshold was disregarded and then normalized to a percentage of the total.


            %% This section completes the calculations and binning.
            if ~isempty(app.UITable2.Data) && ~isempty(app.UITable2_2.Data) % If both TP and FP CL tables have entries
                %% Creating the True Positive Distribution
                
                for i =1:1:length(app.UITable2.Data(:,2)) % For the length of the entire TP table
                    if app.UITable2.Data{i,2} >= 0 && app.UITable2.Data{i,2} < 0.05 % If the CL entry is between 0 and 0.05 (but less then 0.05), increment the value for that bin
                        TruePosBinsCount(1) = TruePosBinsCount(1) + 1;
    
                    elseif app.UITable2.Data{i,2} >= 0.05 && app.UITable2.Data{i,2} < 0.1 % If the CL entry is between 0.05 and 0.1 (but less then 0.1), increment the value for that bin
                        TruePosBinsCount(2) = TruePosBinsCount(2) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.1 && app.UITable2.Data{i,2} < 0.15
                        TruePosBinsCount(3) = TruePosBinsCount(3) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.15 && app.UITable2.Data{i,2} < 0.2
                        TruePosBinsCount(4) = TruePosBinsCount(4) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.2 && app.UITable2.Data{i,2} < 0.25
                        TruePosBinsCount(5) = TruePosBinsCount(5) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.25 && app.UITable2.Data{i,2} < 0.3
                        TruePosBinsCount(6) = TruePosBinsCount(6) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.3 && app.UITable2.Data{i,2} < 0.35
                        TruePosBinsCount(7) = TruePosBinsCount(7) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.35 && app.UITable2.Data{i,2} < 0.4
                        TruePosBinsCount(8) = TruePosBinsCount(8) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.4 && app.UITable2.Data{i,2} < 0.45
                        TruePosBinsCount(9) = TruePosBinsCount(9) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.45 && app.UITable2.Data{i,2} < 0.5
                        TruePosBinsCount(10) = TruePosBinsCount(10) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.5 && app.UITable2.Data{i,2} < 0.55
                        TruePosBinsCount(11) = TruePosBinsCount(11) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.55 && app.UITable2.Data{i,2} < 0.6
                        TruePosBinsCount(12) = TruePosBinsCount(12) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.6 && app.UITable2.Data{i,2} < 0.65
                        TruePosBinsCount(13) = TruePosBinsCount(13) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.65 && app.UITable2.Data{i,2} < 0.7
                        TruePosBinsCount(14) = TruePosBinsCount(14) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.7 && app.UITable2.Data{i,2} < 0.75
                        TruePosBinsCount(15) = TruePosBinsCount(15) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.75 && app.UITable2.Data{i,2} < 0.8
                        TruePosBinsCount(16) = TruePosBinsCount(16) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.8 && app.UITable2.Data{i,2} < 0.85
                        TruePosBinsCount(17) = TruePosBinsCount(17) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.85 && app.UITable2.Data{i,2} < 0.9
                        TruePosBinsCount(18) = TruePosBinsCount(18) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.9 && app.UITable2.Data{i,2} < 0.95
                        TruePosBinsCount(19) = TruePosBinsCount(19) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.95 && app.UITable2.Data{i,2} < 1
                        TruePosBinsCount(20) = TruePosBinsCount(20) + 1;
    
                    end
                end
                TotalNorm = sum(TruePosBinsCount); % Find the total amount of True Positives by summing across all bins
                TruePosBinsCountNorm = TruePosBinsCount; % Working variable 
                TruePosBinsCountNorm = TruePosBinsCountNorm ./ TotalNorm; % Uses total to create normalized distribution from normal distribution
    
                % FindRemainingColumn = zeros(20,1); % Initializes
                
                for i =1:1:length(app.UITable2.Data(:,2)) % For the length of the TP table entries
                    if app.UITable2.Data{i,2} >= 1 % If CL in entry is at or above 1. (not possible, but included for thuroughness and outliers)
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1; % All Bins are incremented by 1.
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                        FindRemainingColumn(18) = FindRemainingColumn(18) + 1;
                        FindRemainingColumn(19) = FindRemainingColumn(19) + 1;
                        FindRemainingColumn(20) = FindRemainingColumn(20) + 1;
                    
                    elseif app.UITable2.Data{i,2} >= 0.95 % If CL in entry is at or above 0.95.
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1; % All Bins are incremented by 1.
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                        FindRemainingColumn(18) = FindRemainingColumn(18) + 1;
                        FindRemainingColumn(19) = FindRemainingColumn(19) + 1;
                        FindRemainingColumn(20) = FindRemainingColumn(20) + 1;
                    
                    elseif app.UITable2.Data{i,2} >= 0.9 % If CL in entry is at or above 0.9
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1; % All bins are incremented by 1, except the last one for 0.95 and above
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                        FindRemainingColumn(18) = FindRemainingColumn(18) + 1;
                        FindRemainingColumn(19) = FindRemainingColumn(19) + 1;
                  
                    
                    elseif app.UITable2.Data{i,2} >= 0.85 % If CL in entry is at or above 0.85
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1; % All bins are incremented by 1, except the last 2 for 0.9 and above and 0.95 and above
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                        FindRemainingColumn(18) = FindRemainingColumn(18) + 1;
                    
                    
                    elseif app.UITable2.Data{i,2} >= 0.8 % This continues for all CL bins
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.75
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                      
                    
                    elseif app.UITable2.Data{i,2} >= 0.7
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.65
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                
                    
                    elseif app.UITable2.Data{i,2} >= 0.6
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.55
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                      
                    
                    elseif app.UITable2.Data{i,2} >= 0.5
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                 
                    
                    elseif app.UITable2.Data{i,2} >= 0.45
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.4
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.35
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                      
                    
                    elseif app.UITable2.Data{i,2} >= 0.3
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.25
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                      
                    
                    elseif app.UITable2.Data{i,2} >= 0.2
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                  
                    elseif app.UITable2.Data{i,2} >= 0.15
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                  
                    
                    elseif app.UITable2.Data{i,2} >= 0.1
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
              
                    
                    elseif app.UITable2.Data{i,2} >= .05
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                      
                    else % If below 0.05, adds one to the lowest possible bin
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                    end
                    
                end

                RemainingNorm = FindRemainingColumn ./TotalNorm; % Uses total remaining to create normalized distribution from normal distribution
    
    
                %% Creating the False Positive Distribution
    
                % FalsPosBinsCount = zeros(20,1); % 

                for i =1:1:length(app.UITable2_2.Data(:,2)) % For the length of the FP CL table entries
                    if app.UITable2_2.Data{i,2} > 0 && app.UITable2_2.Data{i,2} <= 0.05  % If the CL entry is between 0 and 0.05 (but less then 0.05), increment the value for that bin
                        FalsPosBinsCount(1) = FalsPosBinsCount(1) + 1;
    
                    elseif app.UITable2_2.Data{i,2} >= 0.05 && app.UITable2_2.Data{i,2} < 0.1
                        FalsPosBinsCount(2) = FalsPosBinsCount(2) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.1 && app.UITable2_2.Data{i,2} < 0.15
                        FalsPosBinsCount(3) = FalsPosBinsCount(3) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.15 && app.UITable2_2.Data{i,2} < 0.2
                        FalsPosBinsCount(4) = FalsPosBinsCount(4) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.2 && app.UITable2_2.Data{i,2} < 0.25
                        FalsPosBinsCount(5) = FalsPosBinsCount(5) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.25 && app.UITable2_2.Data{i,2} < 0.3
                        FalsPosBinsCount(6) = FalsPosBinsCount(6) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.3 && app.UITable2_2.Data{i,2} < 0.35
                        FalsPosBinsCount(7) = FalsPosBinsCount(7) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.35 && app.UITable2_2.Data{i,2} < 0.4
                        FalsPosBinsCount(8) = FalsPosBinsCount(8) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.4 && app.UITable2_2.Data{i,2} < 0.45
                        FalsPosBinsCount(9) = FalsPosBinsCount(9) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.45 && app.UITable2_2.Data{i,2} < 0.5
                        FalsPosBinsCount(10) = FalsPosBinsCount(10) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.5 && app.UITable2_2.Data{i,2} < 0.55
                        FalsPosBinsCount(11) = FalsPosBinsCount(11) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.55 && app.UITable2_2.Data{i,2} < 0.6
                        FalsPosBinsCount(12) = FalsPosBinsCount(12) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.6 && app.UITable2_2.Data{i,2} < 0.65
                        FalsPosBinsCount(13) = FalsPosBinsCount(13) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.65 && app.UITable2_2.Data{i,2} < 0.7
                        FalsPosBinsCount(14) = FalsPosBinsCount(14) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.7 && app.UITable2_2.Data{i,2} < 0.75
                        FalsPosBinsCount(15) = FalsPosBinsCount(15) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.75 && app.UITable2_2.Data{i,2} < 0.8
                        FalsPosBinsCount(16) = FalsPosBinsCount(16) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.8 && app.UITable2_2.Data{i,2} < 0.85
                        FalsPosBinsCount(17) = FalsPosBinsCount(17) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.85 && app.UITable2_2.Data{i,2} < 0.9
                        FalsPosBinsCount(18) = FalsPosBinsCount(18) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.9 && app.UITable2_2.Data{i,2} < 0.95
                        FalsPosBinsCount(19) = FalsPosBinsCount(19) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.95 && app.UITable2_2.Data{i,2} < 1
                        FalsPosBinsCount(20) = FalsPosBinsCount(20) + 1;
    
                    end
                end
                    TotalNormFals = sum(FalsPosBinsCount); % Find the total amount of False Positives by summing across all bins
                    FalsPosBinsCountNorm = FalsPosBinsCount; % Working Variable
                    FalsPosBinsCountNorm = FalsPosBinsCountNorm ./ TotalNormFals; % Created Normalized distribution from normal distribution of FP CL bin counter column.
    
                    % FindRemainingColumnFals = zeros(20,1);
                
                    for i =1:1:length(app.UITable2_2.Data(:,2)) % For the length of the FP CL table
                        if app.UITable2_2.Data{i,2} >= 1 % If CL in entry is at or above 1. (not possible, but included for thuroughness and outliers)
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1; % Increment all bins by 1
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                            FindRemainingColumnFals(18) = FindRemainingColumnFals(18) + 1;
                            FindRemainingColumnFals(19) = FindRemainingColumnFals(19) + 1;
                            FindRemainingColumnFals(20) = FindRemainingColumnFals(20) + 1;
    
                        elseif app.UITable2_2.Data{i,2} >= .95 % If CL in entry is at or above 0.95. 
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1; % Increment all bins by 1
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                            FindRemainingColumnFals(18) = FindRemainingColumnFals(18) + 1;
                            FindRemainingColumnFals(19) = FindRemainingColumnFals(19) + 1;
                            FindRemainingColumnFals(20) = FindRemainingColumnFals(20) + 1;
                     
    
                        elseif app.UITable2_2.Data{i,2} >= .9 % If CL in entry is at or above 0.90
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1; % Increment all bins by 1 except the last one for 0.95 and above
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                            FindRemainingColumnFals(18) = FindRemainingColumnFals(18) + 1;
                            FindRemainingColumnFals(19) = FindRemainingColumnFals(19) + 1;
                      
    
                        elseif app.UITable2_2.Data{i,2} >= .85
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1; % This continues
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                            FindRemainingColumnFals(18) = FindRemainingColumnFals(18) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .8
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                         
    
                        elseif app.UITable2_2.Data{i,2} >= .75
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                          
    
                        elseif app.UITable2_2.Data{i,2} >= .7
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                           
                        elseif app.UITable2_2.Data{i,2} >= .65
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            
    
                        elseif app.UITable2_2.Data{i,2} >= .6
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .55
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                        
    
                        elseif app.UITable2_2.Data{i,2} >= .5
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                         
    
                        elseif app.UITable2_2.Data{i,2} >= .45
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                         
    

                        elseif app.UITable2_2.Data{i,2} >= .4
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .35
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            
    
                        elseif app.UITable2_2.Data{i,2} >= .3
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .25
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .2
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .15
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                          
    
                        elseif app.UITable2_2.Data{i,2} >= .1
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .05
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                           
    
                        else % If below 0.05, adds one to the lowest possible bin
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1)+1; 


                        end
                    end

                    RemainingNormFals = FindRemainingColumnFals ./TotalNormFals; % Creates normalized distribution from remaining FP by number column.
            elseif ~isempty(app.UITable2.Data) && isempty(app.UITable2_2.Data) % If TP CL table has entries but the FP Cl table does not
                %% Creating the True Positive Distribution

                for i =1:1:length(app.UITable2.Data(:,2)) % For the length of the TP CL table
                    if app.UITable2.Data{i,2} >= 0 && app.UITable2.Data{i,2} < 0.05 % If the CL entry is between 0 and 0.05 (but less then 0.05), increment the value for that bin.
                        TruePosBinsCount(1) = TruePosBinsCount(1) + 1;
    
                    elseif app.UITable2.Data{i,2} >= 0.05 && app.UITable2.Data{i,2} < 0.1
                        TruePosBinsCount(2) = TruePosBinsCount(2) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.1 && app.UITable2.Data{i,2} < 0.15
                        TruePosBinsCount(3) = TruePosBinsCount(3) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.15 && app.UITable2.Data{i,2} < 0.2
                        TruePosBinsCount(4) = TruePosBinsCount(4) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.2 && app.UITable2.Data{i,2} < 0.25
                        TruePosBinsCount(5) = TruePosBinsCount(5) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.25 && app.UITable2.Data{i,2} < 0.3
                        TruePosBinsCount(6) = TruePosBinsCount(6) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.3 && app.UITable2.Data{i,2} < 0.35
                        TruePosBinsCount(7) = TruePosBinsCount(7) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.35 && app.UITable2.Data{i,2} < 0.4
                        TruePosBinsCount(8) = TruePosBinsCount(8) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.4 && app.UITable2.Data{i,2} < 0.45
                        TruePosBinsCount(9) = TruePosBinsCount(9) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.45 && app.UITable2.Data{i,2} < 0.5
                        TruePosBinsCount(10) = TruePosBinsCount(10) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.5 && app.UITable2.Data{i,2} < 0.55
                        TruePosBinsCount(11) = TruePosBinsCount(11) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.55 && app.UITable2.Data{i,2} < 0.6
                        TruePosBinsCount(12) = TruePosBinsCount(12) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.6 && app.UITable2.Data{i,2} < 0.65
                        TruePosBinsCount(13) = TruePosBinsCount(13) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.65 && app.UITable2.Data{i,2} < 0.7
                        TruePosBinsCount(14) = TruePosBinsCount(14) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.7 && app.UITable2.Data{i,2} < 0.75
                        TruePosBinsCount(15) = TruePosBinsCount(15) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.75 && app.UITable2.Data{i,2} < 0.8
                        TruePosBinsCount(16) = TruePosBinsCount(16) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.8 && app.UITable2.Data{i,2} < 0.85
                        TruePosBinsCount(17) = TruePosBinsCount(17) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.85 && app.UITable2.Data{i,2} < 0.9
                        TruePosBinsCount(18) = TruePosBinsCount(18) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.9 && app.UITable2.Data{i,2} < 0.95
                        TruePosBinsCount(19) = TruePosBinsCount(19) + 1;
                    elseif app.UITable2.Data{i,2} >= 0.95 && app.UITable2.Data{i,2} < 1
                        TruePosBinsCount(20) = TruePosBinsCount(20) + 1;
    
                    end
    
    
                end
                
                TotalNorm = sum(TruePosBinsCount); % Creates a sum of all entries by summing all the bins of the TPs
                TruePosBinsCountNorm = TruePosBinsCount; % Working variable
                TruePosBinsCountNorm = TruePosBinsCountNorm ./ TotalNorm; % Creates the normalized distribution based off of the current distribution and the the total amount of detections.
    
                % FindRemainingColumn = zeros(20,1);
                
                for i =1:1:length(app.UITable2.Data(:,2))  % For the length of the TP CL table
                    if app.UITable2.Data{i,2} >= 1 % If CL in entry is at or above 1. (not possible, but included for thuroughness and outliers)
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1; % Increment all bins by 1
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                        FindRemainingColumn(18) = FindRemainingColumn(18) + 1;
                        FindRemainingColumn(19) = FindRemainingColumn(19) + 1;
                        FindRemainingColumn(20) = FindRemainingColumn(20) + 1;
                    
                    elseif app.UITable2.Data{i,2} >= 0.95 % If CL in entry is at or above 0.95.
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1; % Increment all bins by 1
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                        FindRemainingColumn(18) = FindRemainingColumn(18) + 1;
                        FindRemainingColumn(19) = FindRemainingColumn(19) + 1;
                        FindRemainingColumn(20) = FindRemainingColumn(20) + 1;
                    
                    elseif app.UITable2.Data{i,2} >= 0.9 % If CL in entry is at or above 0.90.
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1; % Increment all bins by 1 except for the last one (0.95 and above)
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                        FindRemainingColumn(18) = FindRemainingColumn(18) + 1;
                        FindRemainingColumn(19) = FindRemainingColumn(19) + 1;
                  
                    
                    elseif app.UITable2.Data{i,2} >= 0.85
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                        FindRemainingColumn(18) = FindRemainingColumn(18) + 1;
                    
                    
                    elseif app.UITable2.Data{i,2} >= 0.8
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                        FindRemainingColumn(17) = FindRemainingColumn(17) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.75
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                        FindRemainingColumn(16) = FindRemainingColumn(16) + 1;
                      
                    
                    elseif app.UITable2.Data{i,2} >= 0.7
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                        FindRemainingColumn(15) = FindRemainingColumn(15) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.65
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                        FindRemainingColumn(14) = FindRemainingColumn(14) + 1;
                
                    
                    elseif app.UITable2.Data{i,2} >= 0.6
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                        FindRemainingColumn(13) = FindRemainingColumn(13) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.55
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                        FindRemainingColumn(12) = FindRemainingColumn(12) + 1;
                      
                    
                    elseif app.UITable2.Data{i,2} >= 0.5
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                        FindRemainingColumn(11) = FindRemainingColumn(11) + 1;
                 
                    
                    elseif app.UITable2.Data{i,2} >= 0.45
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                        FindRemainingColumn(10) = FindRemainingColumn(10) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.4
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                        FindRemainingColumn(9) = FindRemainingColumn(9) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.35
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                        FindRemainingColumn(8) = FindRemainingColumn(8) + 1;
                      
                    
                    elseif app.UITable2.Data{i,2} >= 0.3
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                        FindRemainingColumn(7) = FindRemainingColumn(7) + 1;
                     
                    
                    elseif app.UITable2.Data{i,2} >= 0.25
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                        FindRemainingColumn(6) = FindRemainingColumn(6) + 1;
                      
                    
                    elseif app.UITable2.Data{i,2} >= 0.2
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                        FindRemainingColumn(5) = FindRemainingColumn(5) + 1;
                  
                    elseif app.UITable2.Data{i,2} >= 0.15
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
                        FindRemainingColumn(4) = FindRemainingColumn(4) + 1;
                  
                    
                    elseif app.UITable2.Data{i,2} >= 0.1
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                        FindRemainingColumn(3) = FindRemainingColumn(3) + 1;
              
                    
                    elseif app.UITable2.Data{i,2} >= .05
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                        FindRemainingColumn(2) = FindRemainingColumn(2) + 1;
                      
                    else % If below 0.05, adds one to the lowest possible bin
                        FindRemainingColumn(1) = FindRemainingColumn(1) + 1;
                    end
                    
                end
                RemainingNorm = FindRemainingColumn ./TotalNorm; % Creates normalized distribution of remaining TP from current distribution
    
    
                %% Creating the False Positive Distribution
    
                FalsPosBinsCount = zeros(20,1); % Initialize False Positive Bin counter
                FalsPosBinsCountNorm = zeros(20,1); % Initialize False Positive Normalized Bin Counter
                RemainingNormFals = zeros(20,1); % Initialize False Positive Normalized Remaining Entries counter


            elseif isempty(app.UITable2.Data) && ~isempty(app.UITable2_2.Data)  % If FP CL table has entries but the TP Cl table does not
                %% Creating the True Positive Distribution
                TruePosBinsCount = zeros(20,1); % Initialize True Positive Bin Counter
                TruePosBinsCountNorm = zeros(20,1); % Initialize True Positive Normalized Bin Counter
                FindRemainingColumn = zeros(20,1); % Initialize True Positive Remaining Entries counter
                RemainingNorm = zeros(20,1); % Initialize True Positive Normalized Remaining Entries counter
    
    
                %% Creating the False Positive Distribution
    
                FalsPosBinsCount = zeros(20,1); % initializing the False Positive Bin Counter

                for i =1:1:length(app.UITable2_2.Data(:,2)) % For the Length of the FP CL table
                    if app.UITable2_2.Data{i,2} > 0 && app.UITable2_2.Data{i,2} <= 0.05 % If the CL entry is between 0 and 0.05 (but less then 0.05), increment the value for that bin.
                        FalsPosBinsCount(1) = FalsPosBinsCount(1) + 1;
    
                    elseif app.UITable2_2.Data{i,2} >= 0.05 && app.UITable2_2.Data{i,2} < 0.1
                        FalsPosBinsCount(2) = FalsPosBinsCount(2) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.1 && app.UITable2_2.Data{i,2} < 0.15
                        FalsPosBinsCount(3) = FalsPosBinsCount(3) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.15 && app.UITable2_2.Data{i,2} < 0.2
                        FalsPosBinsCount(4) = FalsPosBinsCount(4) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.2 && app.UITable2_2.Data{i,2} < 0.25
                        FalsPosBinsCount(5) = FalsPosBinsCount(5) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.25 && app.UITable2_2.Data{i,2} < 0.3
                        FalsPosBinsCount(6) = FalsPosBinsCount(6) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.3 && app.UITable2_2.Data{i,2} < 0.35
                        FalsPosBinsCount(7) = FalsPosBinsCount(7) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.35 && app.UITable2_2.Data{i,2} < 0.4
                        FalsPosBinsCount(8) = FalsPosBinsCount(8) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.4 && app.UITable2_2.Data{i,2} < 0.45
                        FalsPosBinsCount(9) = FalsPosBinsCount(9) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.45 && app.UITable2_2.Data{i,2} < 0.5
                        FalsPosBinsCount(10) = FalsPosBinsCount(10) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.5 && app.UITable2_2.Data{i,2} < 0.55
                        FalsPosBinsCount(11) = FalsPosBinsCount(11) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.55 && app.UITable2_2.Data{i,2} < 0.6
                        FalsPosBinsCount(12) = FalsPosBinsCount(12) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.6 && app.UITable2_2.Data{i,2} < 0.65
                        FalsPosBinsCount(13) = FalsPosBinsCount(13) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.65 && app.UITable2_2.Data{i,2} < 0.7
                        FalsPosBinsCount(14) = FalsPosBinsCount(14) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.7 && app.UITable2_2.Data{i,2} < 0.75
                        FalsPosBinsCount(15) = FalsPosBinsCount(15) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.75 && app.UITable2_2.Data{i,2} < 0.8
                        FalsPosBinsCount(16) = FalsPosBinsCount(16) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.8 && app.UITable2_2.Data{i,2} < 0.85
                        FalsPosBinsCount(17) = FalsPosBinsCount(17) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.85 && app.UITable2_2.Data{i,2} < 0.9
                        FalsPosBinsCount(18) = FalsPosBinsCount(18) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.9 && app.UITable2_2.Data{i,2} < 0.95
                        FalsPosBinsCount(19) = FalsPosBinsCount(19) + 1;
                    elseif app.UITable2_2.Data{i,2} >= 0.95 && app.UITable2_2.Data{i,2} < 1
                        FalsPosBinsCount(20) = FalsPosBinsCount(20) + 1;
    
                    end
                end
                    TotalNormFals = sum(FalsPosBinsCount);  % Creates a sum of all entries by summing all the bins of the FPs
                    FalsPosBinsCountNorm = FalsPosBinsCount;  % Working Variable
                    FalsPosBinsCountNorm = FalsPosBinsCountNorm ./ TotalNormFals; % Created Normalized distribution from normal distribution of FP CL bin counter column.
    
                    FindRemainingColumnFals = zeros(20,1); % Initialize False Positive Remaining column distribution
                
                    for i =1:1:length(app.UITable2_2.Data(:,2)) % For the length of the FP CL table
                        if app.UITable2_2.Data{i,2} >= 1  % If CL in entry is at or above 1. (not possible, but included for thuroughness and outliers)
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1; % Increment all bins by 1
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                            FindRemainingColumnFals(18) = FindRemainingColumnFals(18) + 1;
                            FindRemainingColumnFals(19) = FindRemainingColumnFals(19) + 1;
                            FindRemainingColumnFals(20) = FindRemainingColumnFals(20) + 1;
    
                        elseif app.UITable2_2.Data{i,2} >= .95 % If CL in entry is at or above 0.95.
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1; % Increment all bins by 1
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                            FindRemainingColumnFals(18) = FindRemainingColumnFals(18) + 1;
                            FindRemainingColumnFals(19) = FindRemainingColumnFals(19) + 1;
                            FindRemainingColumnFals(20) = FindRemainingColumnFals(20) + 1;
                     
    
                        elseif app.UITable2_2.Data{i,2} >= .9 % If CL in entry is at or above 0.90.
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1; % Increment all bins by 1 except for the last one (0.95 and above)
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                            FindRemainingColumnFals(18) = FindRemainingColumnFals(18) + 1;
                            FindRemainingColumnFals(19) = FindRemainingColumnFals(19) + 1;
                      
    
                        elseif app.UITable2_2.Data{i,2} >= .85
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                            FindRemainingColumnFals(18) = FindRemainingColumnFals(18) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .8
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                            FindRemainingColumnFals(17) = FindRemainingColumnFals(17) + 1;
                         
    
                        elseif app.UITable2_2.Data{i,2} >= .75
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                            FindRemainingColumnFals(16) = FindRemainingColumnFals(16) + 1;
                          
    
                        elseif app.UITable2_2.Data{i,2} >= .7
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            FindRemainingColumnFals(15) = FindRemainingColumnFals(15) + 1;
                           
                        elseif app.UITable2_2.Data{i,2} >= .65
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                            FindRemainingColumnFals(14) = FindRemainingColumnFals(14) + 1;
                            
    
                        elseif app.UITable2_2.Data{i,2} >= .6
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                            FindRemainingColumnFals(13) = FindRemainingColumnFals(13) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .55
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                            FindRemainingColumnFals(12) = FindRemainingColumnFals(12) + 1;
                        
    
                        elseif app.UITable2_2.Data{i,2} >= .5
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                            FindRemainingColumnFals(11) = FindRemainingColumnFals(11) + 1;
                         
    
                        elseif app.UITable2_2.Data{i,2} >= .45
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                            FindRemainingColumnFals(10) = FindRemainingColumnFals(10) + 1;
                         
    
                        elseif app.UITable2_2.Data{i,2} >= .4
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            FindRemainingColumnFals(9) = FindRemainingColumnFals(9) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .35
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                            FindRemainingColumnFals(8) = FindRemainingColumnFals(8) + 1;
                            
    
                        elseif app.UITable2_2.Data{i,2} >= .3
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                            FindRemainingColumnFals(7) = FindRemainingColumnFals(7) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .25
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                            FindRemainingColumnFals(6) = FindRemainingColumnFals(6) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .2
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                            FindRemainingColumnFals(5) = FindRemainingColumnFals(5) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .15
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                            FindRemainingColumnFals(4) = FindRemainingColumnFals(4) + 1;
                          
    
                        elseif app.UITable2_2.Data{i,2} >= .1
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                            FindRemainingColumnFals(3) = FindRemainingColumnFals(3) + 1;
                           
    
                        elseif app.UITable2_2.Data{i,2} >= .05
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1) + 1;
                            FindRemainingColumnFals(2) = FindRemainingColumnFals(2) + 1;
                           
    
                        else % If below 0.05, adds one to the lowest possible bin
                            FindRemainingColumnFals(1) = FindRemainingColumnFals(1)+1;


                        end
                    end
                    RemainingNormFals = FindRemainingColumnFals ./TotalNormFals; % Create Normalized distribution of FP remaining at CL threshold or above
            
            end


                %% Create final distribution table for use in creating plots

                FullPlottingTable = table(Distro,TruePosBinsCount,FalsPosBinsCount,TruePosBinsCountNorm,FalsPosBinsCountNorm, FindRemainingColumn,FindRemainingColumnFals,RemainingNorm,RemainingNormFals);
                app.CLDistributionTable = FullPlottingTable;

                app.CLDistributionPlotButton.Enable = 'on'; % Allows Plots to be made now that the distribution table is made
                app.GenerateDetectionNumberPlotButton.Enable = 'on'; % Allows Plots to be made now that the distribution table is made
                app.GenerateDetectionPercentagePlotButton.Enable = 'on'; % Allows Plots to be made now that the distribution table is made
                app.OpenCurrentPlottoFigureButton.Enable = 'on'; % Allows Plots to be made now that the distribution table is made
                app.CurrentPlot = "none"; % Allows Plots to be made now that the distribution table is made
                app.CLThresholdSlider_2.Enable = 'on'; % Allows Plots to be made now that the distribution table is made
                app.SliderOnPlotButtonGroup.Enable = 'on'; % Allows Plots to be made now that the distribution table is made


        end

        % Button pushed function: GenerateDetectionPercentagePlotButton
        function GenerateDetectionPercentagePlotButtonPushed(app, event)
           %% This function takes the columns of the distribution table relating to the Normalized TP and FPs remaining and plots them.
            
           xlabel(app.UIAxes,'Confidence Levels')
           ylabel(app.UIAxes,'Percentage of Detections')
           title(app.UIAxes,'Detections At or Above a CL by Percentage')
           plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,8}) % Plots detections by normalized percentage (TP) (Y) at or above a CL Bin (X). Ex. 50% Detections remain above a 0.6 CL
           hold(app.UIAxes,"on")
           plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,9}) % Plots detections by normalized percentage (FP) (Y) at or above a CL Bin (X). Ex. 50% Detections remain above a 0.6 CL
           hold(app.UIAxes,"off")
           lgd = legend(app.UIAxes,'TP > CL','FP > CL',"Location","northwest");
           app.CurrentPlot = "DetectionPercentage"; % This property used to identify specific plot type later on

        end

        % Button pushed function: OpenCurrentPlottoFigureButton
        function OpenCurrentPlottoFigureButtonPushed(app, event)
            %% This function takes the current plot and exports it to a matlab figure for further manipulation and saving
            
            t = app.UIAxes; % Create working plot, copy of the current one
            positioncurrent = app.UIAxes.Position; % Gets the current position of the Plot for use of resetting it back to its original later
            if app.CurrentPlot == "CLDistribution" % Internal property to the app that tracks what plot is the current
               
                j = figure; % creates a new figure
                t.Position = [10 10 500 400]; % Creates default position for Matlab figures, if just export from app it is weirdly proportioned.
                pause(1); %matlab behavior changes if I dont slow it down
                copyobj([t,app.CurrentLegend],j) % Copys the temp plot to the new figure.
                t.Position = positioncurrent; % Resets the position of the matlab app plot
                savefig(j,"CLDistribution.fig"); % Saves the figure in the working directory
            elseif app.CurrentPlot == "DetectionPercentage"
                
                j = figure;
                t.Position = [10 10 500 400]; % Creates default position for Matlab figures, if just export from app it is weirdly proportioned.
                pause(1); %matlab behavior changes if I dont slow it down
                copyobj([t,app.CurrentLegend],j) % Copys the temp plot to the new figure.
                t.Position = positioncurrent; % Resets the position of the matlab app plot
                savefig(j,"DetectionPercentage.fig");
            elseif app.CurrentPlot == "DetectionNumber"
                
                j = figure;
                t.Position = [10 10 500 400]; % Creates default position for Matlab figures, if just export from app it is weirdly proportioned.
                pause(1); %matlab behavior changes if I dont slow it down
                copyobj([t,app.CurrentLegend],j) % Copys the temp plot to the new figure.
                t.Position = positioncurrent; % Resets the position of the matlab app plot
                savefig(j,"DetectionNumber.fig");
            else
                alert = warndlg("PLOT IS UNINITIALIZED", "INVALID ENTRY"); % Displays alert to the User that the Plot doesnt currently have a setting, which means it wasnt initialized.

               
            end
        end

        % Button pushed function: LoadTPTableButton
        function LoadTPTableButtonPushed(app, event)
            %% This function Loads a table into the True Postive table into the app.
            t = []; % Initialize Temp variable
            if ~(isempty(app.UITable2.Data)) % If there is already data inside the table, copy it to the temp variable.
                t = app.UITable2.Data;
            end
            [TPFile, TPFilepath] = uigetfile('.mat', 'Select Mat table to load'); % User can select the mat table to load
            Location = string(TPFile); % Takes the location of the TP file and keeps it as a string
            if ~(Location == "0") % If the UI is not canceled out of
                StructTP = load(Location); % Loads the mat file, requires it to be in the same directory as the matlab app and everything else to function
                TableTPInterim = struct2table(StructTP); % Converts it from its natural struct to a table
                
                counterTable = size(TableTPInterim); % Finds the size of the loaded table
                for i = 1:1:counterTable(1) % For the length of the table 
                    TableTPInterim2 = TableTPInterim{i,1}; % Split into its bunched together struct column
                    TableTPNum{i} = TableTPInterim2{1}; % Split it further (entry number)
                    TableTPVal{i} = TableTPInterim2{2}; % Split it furhter (Values)
                end
                TableTPNumTrans = transpose(TableTPNum); % Make it a column vector
                TableTPValTrans = transpose(TableTPVal); % Make it a column vector
    
                FinTable = table(TableTPNumTrans,TableTPValTrans); % create the final table
                FinTableCell = table2cell(FinTable); % turn the table into a cell to match how App Designer table data works.
               
                app.UITable2.Data = FinTableCell; % Display this table to the User.
            elseif (Location == "0") && ~(isempty(t)) % If canceled out of, and had previous data, reloads the last entry. 
                app.UITable2.Data = t;
            end
        end

        % Button pushed function: LoadFPTableButton
        function LoadFPTableButtonPushed(app, event)
            %% This function Loads a table into the False Postive table into the app.

            t = [];  % Initialize Temp variable
            if ~(isempty(app.UITable2_2.Data)) % If there is already data inside the table, copy it to the temp variable.
                t = app.UITable2_2.Data;
            end
            [FPFile, FPFilepath] = uigetfile('.mat', 'Select Mat table to load'); % User can select the mat table to load
            Location = string(FPFile); % Takes the location of the FP file and keeps it as a string
            if ~(Location == "0")
                StructFP = load(Location); % Loads the mat file, requires it to be in the same directory as the matlab app and everything else to function
                TableFPInterim = struct2table(StructFP);  % Converts it from its natural struct to a table
                
                counterTable = size(TableFPInterim); % Finds the size of the loaded table
                for i = 1:1:counterTable(1) % For the length of the table 
                    TableFPInterim2 = TableFPInterim{i,1}; % Split into its bunched together struct column
                    TableFPNum{i} = TableFPInterim2{1}; % Split it further (entry number)
                    TableFPVal{i} = TableFPInterim2{2}; % Split it furhter (Values)
                end
                TableFPNumTrans = transpose(TableFPNum); % Make it a column vector
                TableFPValTrans = transpose(TableFPVal); % Make it a column vector
    
                FinTable = table(TableFPNumTrans,TableFPValTrans); % create the final table
                FinTableCell = table2cell(FinTable);  % turn the table into a cell to match how App Designer table data works.
               
                app.UITable2_2.Data = FinTableCell; % Display this table to the User.
            elseif (Location == "0") && ~(isempty(t)) % If canceled out of, and had previous data, reloads the last entry. 
                app.UITable2_2.Data = t;
            end
            
        end

        % Callback function
        function TrainingSetUpButtonGroupSelectionChanged(app, event)
            %% This function controls which options are displayed to the User, between Automatic and Manual
            
            selectedButton = app.TrainingSetUpButtonGroup.SelectedObject; % Value chosen by User
            if selectedButton.Text == "Automatic" 
                app.AutomaticTrainingSetUpPanel.Visible = "on"; % Make Automatic panel visible
                app.ManualEditingTrainingSetUpPanel.Visible = "off"; % Make Manual panel invisible
            elseif selectedButton.Text ==  "Manual"
                app.AutomaticTrainingSetUpPanel.Visible = "off"; % Make Automatic panel invisible
                app.ManualEditingTrainingSetUpPanel.Visible = "on"; % Make Manual panel visible

            end
        end

        % Button pushed function: TRAINMODELButton
        function TRAINMODELButtonPushed(app, event)
            %% This function trains the model automatically in powershell while also keeping it open
            
            if app.ProcessorListBox.Value == "GPU" % User selected, converted into format accepted by python.py
                varProcessor = "0";
            elseif app.ProcessorListBox.Value == "CPU" % User selected, converted into format accepted by python.py
                varProcessor = "cpu";
            end
            if app.ModelSizeListBox.Value == "Nano" % User selected, converted into format accepted by python.py
                varSize = "yolov5n.pt";
            elseif app.ModelSizeListBox.Value == "Small" % User selected, converted into format accepted by python.py
                varSize = "yolov5s.pt";
            elseif app.ModelSizeListBox.Value == "Medium" % User selected, converted into format accepted by python.py
                varSize = "yolov5m.pt";
            elseif app.ModelSizeListBox.Value == "Large" % User selected, converted into format accepted by python.py
                varSize = "yolov5l.pt";
            elseif app.ModelSizeListBox.Value == "Extra Large" % User selected, converted into format accepted by python.py
                varSize = "yolov5x.pt";
            end
            VarEpoch = app.EpochsEditField.Value; % User selected, determines how many cycles a model will be trained
            varBatchSize = app.BatchSizeEditField.Value; % User selected, determines how many images will be grabbed in "Batches" for training. 
            varImageSZ = app.ImageSizeEditField.Value; % User inputted, determines the resolution the images will be converted to. 
            if ~(app.YAMLFileFilepathEditField.Value == "00") && ~(isempty(app.YAMLFileFilepathEditField.Value)) % If The Yaml filepath isnt empty or uninitialized
                newStr = split(app.YAMLFileFilepathEditField.Value, "\"); % Split the filepath into chunks
                varYamlfilelocationPre = newStr(end); % The relative path from the project working directory
                varYamlfilelocation = varYamlfilelocationPre{1}; % Taking it out of the cell container
                commandone = "cd '+app.ProjectFilepathEditField.Value+'\;"; % Seperating it for ease of understanding
                if ~(app.ProjectFilepathEditField.Value == "0") && ~(isempty(app.ProjectFilepathEditField.Value)) % if project filepath isnt empty or uninitialized
                    system('start powershell -NoExit -Command "cd '+string(app.ProjectFilepathEditField.Value)+'\; python train.py --data '+string(varYamlfilelocation)+' --epochs '+string(VarEpoch)+' --weights '+varSize+' --batch-size '+string(varBatchSize)+' --img '+string(varImageSZ)+' --device ' +varProcessor+'"'); % Opens powershell and runs these commands
                else
                    alert = warndlg("PROJECT FILEPATH MISSING: GO TO PROJECT SET UP TAB", "INVALID ENTRY"); % The project filepath is missing which means it cant find relative routes
                end
            else
                alert = warndlg("YAML FILEPATH MISSING: GO TO TRAINING DATA INPUT TAB", "INVALID ENTRY"); % The Yaml filepath is missing which means it cant find the data it needs to train


            end


        end

        % Callback function
        function TestingSetUpButtonGroupSelectionChanged(app, event)
            %% This function controls which panel is visible for the Testing Model page
            
            selectedButton = app.TestingSetUpButtonGroup.SelectedObject; % User selection
            if selectedButton.Text == "Automatic" 
                app.AutomaticTestingPanel.Visible = "on"; % Automatic Panel is visible
                app.ManualTestingPanel.Visible = "off"; % Manual Panel is invisible
            elseif selectedButton.Text == "Manual"
                app.AutomaticTestingPanel.Visible = "off"; % Automatic Panel is invisible
                app.ManualTestingPanel.Visible = "on"; % Manual Panel is visible
            end

        end

        % Button pushed function: TESTMODELButton
        function TESTMODELButtonPushed(app, event)
            %% This function opens powershell and automatically begins a testing run
            
            if ~(app.YAMLFileFilepathEditField.Value == "00") && ~(isempty(app.YAMLFileFilepathEditField.Value)) % If the YAML filepath isnt empty or uninitialized
            
                newStr = split(app.YAMLFileFilepathEditField.Value, "\"); % Split the filepath
                Yamlfilelocation = newStr(end); % Get the end, which is the name of the YAML file
            else
                alert = warndlg("YAML FILEPATH MISSING: GO TO TRAINING DATA INPUT TAB", "INVALID ENTRY");
            end

            if ~(app.TestDataFilepathEditField.Value == "0") && ~(isempty(app.TestDataFilepathEditField.Value)) % If the Test Data filepath isnt empty or uninitialized

                newStr2 = split(app.TestDataFilepathEditField.Value,"\"); % Split the filepath
                TestDataFileLocation = newStr2(end-1:end); % Grab the relative route from the working project directory
                TestDataFileLocation2 = ""; % Initialize working variable
                for s = 1:size(TestDataFileLocation)
                    if s == 1 % If it is the first section of the rebuilt filepath
                        TestDataFileLocation2 = TestDataFileLocation2 + string(TestDataFileLocation(s));
                    else % All later sections include the backslash
                        TestDataFileLocation2 = TestDataFileLocation2 + "\" + string(TestDataFileLocation(s));
                    end
                    s = s + 1; % Counter variable
    
                end
                TestDataFileLocation = TestDataFileLocation2; % Copy working variable to final variable
            else
                alert = warndlg("TEST DATA FILEPATH MISSING", "INVALID ENTRY");
            end

            if ~(app.ModelWeightsFilepathEditField.Value == "00") && ~(isempty(app.ModelWeightsFilepathEditField.Value)) % if the Weights filepath isnt empty or uninitialized

                newStr3 = split(app.ModelWeightsFilepathEditField.Value  , "\"); % Split the filepath
                weights = newStr3(end-4:end); % Grab the relative route from the working project directory.
                weights2 = ""; % Initialize working variable
                for s = 1:size(weights) % for the length of weights
                    if s == 1 % First part of rebuilt filepath
                        weights2 = weights2 + string(weights(s)); 
                    else % all later sections include the backslash
                        weights2 = weights2 + "\" + string(weights(s));
                    end
                    s = s + 1; % counter variable
    
                end
                weights = weights2; %Copy working variable to final variable
            else
                alert = warndlg("WEIGHTS FILEPATH MISSING", "INVALID ENTRY");
            end

            stringimgsize = string(app.ImageSizeEditField_2.Value); % User inputted, make into a string
            if ~(app.YAMLFileFilepathEditField.Value == "00") && ~(isempty(app.YAMLFileFilepathEditField.Value)) && ~(app.TestDataFilepathEditField.Value == "0") && ~(isempty(app.TestDataFilepathEditField.Value)) && ~(app.WeightsFilepathEditField.Value == "00") && ~(isempty(app.WeightsFilepathEditField.Value)) && ~(stringimgsize == "0") % if nothing is missing
                if ~(app.ProjectFilepathEditField.Value == "0") && ~(isempty(app.ProjectFilepathEditField.Value)) % If the project filepath is set
                    system('start powershell -NoExit -Command "cd '+string(app.ProjectFilepathEditField.Value)+'\; python detect.py --weights '+weights+' --data '+string(Yamlfilelocation)+' --source '+string(TestDataFileLocation)+' --img '+stringimgsize+'"') % Powershell command in format test.py requires
                else
                    alert = warndlg("PROJECT POINTER FILEPATH MISSING", "INVALID ENTRY");
                end
            end
        end

        % Button pushed function: ExportMetricstoTxtButton
        function ExportMetricstoTxtButtonPushed(app, event)
            %% This function creates a txt file with the detection metrics saved
            
            MetToPrint = [app.TotalTruePositivesEditField.Value; app.TotalFalsePositivesEditField.Value; app.TotalObjectsPresentEditField.Value; app.DetectionRateEditField.Value]; % Takes variables to write into txt file
            fileID = fopen('DetectionMetrics.txt','w'); % creates txt file
            fprintf(fileID, '%6s %6s %6s %6s\n','True Positives','False Positives','Total Objects','Detection Rate') % Creates format and structure of text file
            fprintf(fileID, '%1u %13u %16u %19f\n', MetToPrint); % Paste the values into the file
            fclose(fileID); % close the file
            alert = warndlg("Saved to DetectionMetrics.txt", "SAVED");
        end

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            %% This function loads a detection metrics table
            t = [];
            if ~(isempty(app.UITable.Data))
                t = app.UITable.Data;
            end
            [TabFile, TabFilepath] = uigetfile('.mat', 'Select Mat table to load'); % User selected mat table
            Location = string(TabFile); % Turns filepath into a string
            if ~(Location == "0")
                StructTab = load(Location); % loads from filepath
                TableTabInterim = struct2table(StructTab); % converts a table from the structure loaded
                
                counterTable = size(TableTabInterim); 
                for i = 1:1:counterTable(1) % for the size of the table
                    TableTabInterim2 = TableTabInterim{i,1}; % Pulls out from container its in
                    TableTabNum{i} = TableTabInterim2{1}; % Entry Num
                    TableTabDets{i} = TableTabInterim2{2}; % Detections
                    TableTabTP{i} = TableTabInterim2{3};  % True Positives
                    TableTabFP{i} = TableTabInterim2{4}; % False Positives
                    TableTabFN{i} = TableTabInterim2{5}; % False Negative
                end
                TableTabNumTrans = transpose(TableTabNum); % Convert to column vector
                TableTabDetTrans = transpose(TableTabDets); % Convert to column vector
                TableTabTPTrans = transpose(TableTabTP); % Convert to column vector
                TableTabFPTrans = transpose(TableTabFP); % Convert to column vector
                TableTabFNTrans = transpose(TableTabFN); % Convert to column vector
    
                FinTable = table(TableTabNumTrans,TableTabDetTrans,TableTabTPTrans,TableTabFPTrans,TableTabFNTrans); % create finalized table format
                FinTableCell = table2cell(FinTable); % Convert to cell to match format of all other functions and AppDesigner tables
               
                app.UITable.Data = FinTableCell;
            elseif (Location == "0") && ~(isempty(t))
                app.UITable.Data = t;
            
            end
            
        end

        % Value changing function: CLThresholdSlider_2
        function CLThresholdSlider_2ValueChanging(app, event)
            %% This function controls the behavior of the CL threshold slider on the Analysis Plotting subpage
            
            changingValue = event.Value; % User Inputted
            handle = findobj(app.UIAxes,'Type','Line'); % Function to find how many series are in a plot and to give each of them handles
            if height(handle) > 2 % If the line for CL threshold already exists
                delete(handle(1)); % Delete it
            end
            if app.YesButton.Value == 1 % If CL threshold on plot is enabled
                if app.CurrentPlot=="CLDistribution" % and the plot is a CL distribution
                   
                   hold(app.UIAxes,"on") % Keep the plot axes the same
                   plot(app.UIAxes,[1;1]*changingValue,([0 1]),"yellow") % Create a vertical yellow line where the CL threshold slider is set
                   hold(app.UIAxes, "off") 
                   lgd = legend(app.UIAxes,'True Positives','False Positives','CL Threshold',"Location","northwest"); % Update the legend
                   app.CurrentLegend = lgd; % Update the legend displayed to the User
    
                elseif app.CurrentPlot == "DetectionNumber" % and the plot is detection number
                   
                   hold(app.UIAxes,"on") % keep the plot axes the same
                   plot(app.UIAxes,[1;1]*changingValue,([app.UIAxes.YLim(1) app.UIAxes.YLim(2)]),"yellow") % Create a vertical yellow line where the CL threshold slider is set with vertical limits of the current graph 
                   hold(app.UIAxes,"off")
                   lgd = legend(app.UIAxes,'TP > CL','FP > CL','CL Threshold',"Location","northwest"); % update the legend
                   app.CurrentLegend = lgd; % update the legend displayed to the User
    
                elseif app.CurrentPlot == "DetectionPercentage" % and the plot is detection percentage
                   hold(app.UIAxes,"on") % keep the current plot axes the same
                   plot(app.UIAxes,[1;1]*changingValue,([app.UIAxes.YLim(1) app.UIAxes.YLim(2)]), "yellow") % create a vertical yellow line where the CL threshold slider is set
                   hold(app.UIAxes,"off")
                   lgd = legend(app.UIAxes,'TP > CL','FP > CL','CL Threshold',"Location","northwest"); % update the legend
                   app.CurrentLegend = lgd; % update the legend displayed to the User
    
                end
            end
            
        end

        % Selection changed function: SliderOnPlotButtonGroup
        function SliderOnPlotButtonGroupSelectionChanged(app, event)
            %% This function controls whether the CL threshold slider line is enabled, and resets the plots to turn it off if previously enabled
            
            selectedButton = app.SliderOnPlotButtonGroup.SelectedObject;
            if app.NoButton.Value == 1 % If the CL threshold slider on plot is turned off
                if app.CurrentPlot=="CLDistribution" % Whatever the current plot is, replot it without the CL threshold
                   xlabel(app.UIAxes,'Confidence of Prediction')
                   ylabel(app.UIAxes,'Frequency Percentage')
                   title(app.UIAxes,'Confidence Level Distribution Across All Images')
                   plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,4}) 
                   hold(app.UIAxes,"on")
                   plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,5})
                   
                   hold(app.UIAxes, "off")
                   lgd = legend(app.UIAxes,'True Positives','False Positives',"Location","northwest");
                   app.CurrentLegend = lgd;
    
                elseif app.CurrentPlot == "DetectionNumber"
                   xlabel(app.UIAxes,'Confidence Levels')
                   ylabel(app.UIAxes,'Number of Detections')
                   title(app.UIAxes,'Detections At or Above a CL by Number')
                   plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,6}) 
                   hold(app.UIAxes,"on")
                   plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,7})
                   
                   hold(app.UIAxes,"off")
                   legend(app.UIAxes,'TP > CL','FP > CL',"Location","northwest")
                   app.CurrentPlot = "DetectionNumber";
    
                elseif app.CurrentPlot == "DetectionPercentage"
                   xlabel(app.UIAxes,'Confidence Levels')
                   ylabel(app.UIAxes,'Percentage of Detections')
                   title(app.UIAxes,'Detections At or Above a CL by Percentage')
                   plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,8})  % Plots detections by normalized percentage (TP) (Y) at or above a CL Bin (X). Ex. 50% Detections remain above a 0.6 CL
                   hold(app.UIAxes,"on")
                   plot(app.UIAxes,app.CLDistributionTable{:,1},app.CLDistributionTable{:,9})  % Plots detections by normalized percentage (FP) (Y) at or above a CL Bin (X). Ex. 50% Detections remain above a 0.6 CL
                   
                   hold(app.UIAxes,"off")
                   lgd = legend(app.UIAxes,'TP > CL','FP > CL',"Location","northwest");
                   app.CurrentPlot = "DetectionPercentage";
    
                end
            end
            
                
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            %% This function automatically opens a powershell and begins exporting a model to tflite
            
            if ~(app.ModelWeightsFilepathEditField.Value == "0") && ~(isempty(app.ModelWeightsFilepathEditField.Value)) % If weights filepath is not empty or uninitialized
            
                newStr3 = split(app.ModelWeightsFilepathEditField.Value , "\"); % split the weights filepath
                weights = newStr3(end-4:end); % Grab the relative route from working project directory
                weights2 = ""; % Initializes working variable
                for s = 1:size(weights) % For the current length of the filepath in sections
                    if s == 1 % Create the base root of the filepath
                        weights2 = weights2 + string(weights(s));  
                    else % For every section after add the backslash
                        weights2 = weights2 + "\" + string(weights(s));
                    end
                    s = s + 1; % Counter variable
    
                end
                weights = weights2; % copy working variable to final variable
            else
                alert = warndlg("WEIGHTS FILEPATH MISSING", "INVALID ENTRY");
            end
            if ~(app.ProjectFilepathEditField.Value == "0") && ~(isempty(app.ProjectFilepathEditField.Value)) % If the project is not empty or unintialized
                system('start powershell -NoExit -Command "cd '+string(app.ProjectFilepathEditField.Value)+'\; python export.py --img '+string(app.ImageSizeEditField_3.Value)+' --weights '+weights+' --include tflite"') % Open powershell and input this command as needed by export.py
            else
                alert = warndlg("PROJECT POINTER FILEPATH MISSING", "INVALID ENTRY");
            end

        end

        % Button pushed function: SetProjectPointerButton
        function SetProjectPointerButtonPushed(app, event)
            %% This function handles setting the relative path of the project and is the most important pointer function
            t = []; % Initializes temp Variable
           
            if ~(app.ProjectFilepathEditField.Value) % If the value is not empty
                t = app.ProjectFilepathEditField.Value; % Copy value into temp variables
            end

            ProjectDirectory = uigetdir('C:\Users\ctitus\Documents\'); % UI window to find main project directory

            TrainingDirectory2 = string(ProjectDirectory); % Creates variable for testing if UI was canceled
            if ~(TrainingDirectory2 == "0")
                app.ProjectFilepathEditField.Value = string(ProjectDirectory); % Displays filepath in Training Images Filepath text field back in App

            elseif (TrainingDirectory2 == "0") && ~(isempty(t))
                app.ProjectFilepathEditField.Value = t; % Sets various volume mount display fields back to their previous value.


            end

        end

        % Button pushed function: CreateFoldersButton
        function CreateFoldersButtonPushed(app, event)
            %% This function creates a folder inside the working project as well as copies the contents of the Training Input data into it.
            
            if ~(app.ImagesFilepathEditField.Value == "0") && ~(isempty(app.ImagesFilepathEditField.Value)) % If the training images is not empty or uninitalized 
                if ~(isempty(app.NameofFolderEditField.Value))
                    foldername = app.NameofFolderEditField.Value; % User inputted folder name inside of project
                    location = string((app.ProjectFilepathEditField.Value))+ "\data\"; % Find location of project filepath
                    % location2 = string((app.ProjectFilepathEditField.Value))+ "\data";
                    yamlfileName = foldername + ".yaml"; % Create new YAML file name to be same as the folder
                    locationNew = location + string(foldername); % Create new location for the folder
                    pathString = string(app.ImagesFilepathEditField.Value)+ "\*"; % Creates format for path of initial training images directory
                    % finalYamlLocation = locationNew+"\" + yamlfileName;
                    
                    
                    %dlg2 = warndlg('A POWERSHELL WILL OPEN ONCE THIS IS CLOSED, IF DISPLAYING ERROR DISREGARD, IF PROMPT HIT CONFIRM TO DELETE EXISTING FOLDER','WARNING');
                    %waitfor(dlg2) % This waits until the above dialog has closed
                    
                    system('start powershell -NoExit -Command "cd '+string(app.ProjectFilepathEditField.Value)+'\data\;rmdir '+foldername+';mkdir '+foldername+';Copy-Item -Path '+pathString+' -Destination '+string(locationNew)+' -Recurse;Rename-Item -Path '+string(locationNew)+'\data.yaml -NewName '+yamlfileName+'"') % Open powershell and remove the folder if it already exists, will end in a powershell error if a new folder
                    
        
                    %dlg = warndlg('GO TO POWERSHELL AND CONFIRM REMOVING FOLDER BEFORE CLOSING THIS','WARNING'); % Opens Dialog
                    
                    %waitfor(dlg); % Pauses code until Dialog is closed
        
                    %system('start powershell -NoExit -Command "cd '+string(app.ProjectFilepathEditField.Value)+'\data\;mkdir '+foldername+';Copy-Item -Path '+pathString+' -Destination '+string(locationNew)+' -Recurse;Rename-Item -Path '+string(locationNew)+'\data.yaml -NewName '+yamlfileName+'"') % Opens powershell and creates a folder in the data directory, copies the training images to it, and renames the YAML file
                    app.FolderLocationEditField.Value = location + foldername; % Displays where the new folder is to the User
                else
                    alert = warndlg("Input Folder Name", "INVALID ENTRY");

                end
                
            else
                alert = warndlg("YAML FILEPATH MISSING", "INVALID ENTRY");

            end


        end

        % Button pushed function: DockerTrainButton
        function DockerTrainButtonPushed(app, event)
            %% For Manual Mode, generates the code that is presented
            % This section creates the size of the model based on the input
            % of the user. It translates the option chosen into the
            % formatting the Python function requires. (train.py)
            if app.ModelSizeListBox.Value == "Nano"
                modelsize = "yolov5n.pt";
            elseif app.ModelSizeListBox.Value == "Small"
                modelsize = "yolov5s.pt";
            elseif app.ModelSizeListBox.Value == "Medium"
                modelsize = "yolov5m.pt";
            elseif app.ModelSizeListBox.Value == "Large"
                modelsize = "yolov5l.pt";
            elseif app.ModelSizeListBox.Value == "Extra Large"
                modelsize = "yolov5x.pt";
            end

            % This section relates to translating the processing method
            % options into the formatting the python function requires.
            % (train.py)
            if app.ProcessorListBox.Value == "GPU"
                processorSize = "0";
            else
                processorSize = "cpu";
            end


            % This section splits up the YAML filepath to find the relative
            % path from the start of the project, as that is the working
            % directory of the python training function (train.py)
            newStr = split(app.YAMLFileFilepathEditField.Value, "\");
            Yamlfilelocation = newStr(end);
            %Yamlfilelocation = string(app.NameOfYAMLFileInContainerEditField.Value) + ".yaml"; 
            varImageSZ = app.ImageSizeEditField.Value; % User inputted, determines the resolution the images will be converted to. 

            % Generates the code and displays it to the user in an easy to
            % copy field for pasting into powershell. This is the format it
            % requires.
            if (isempty(app.YAMLFileFilepathEditField.Value))
                alert = warndlg("Missing YAML file Entry, will not work", "INVALID ENTRY");
            end

            if ~(app.ContainerIDEditField.Value == "0") && ~(isempty(app.ContainerIDEditField.Value)) % If the container ID field is not empty or blank
                
                textGenerate1 = "docker start "+ string(app.ContainerIDEditField.Value)+"; docker exec -it -u root "+ string(app.ContainerIDEditField.Value)+" sh"; % command to start and enter into a previously started container.
                textGenerate2 =  "python train.py --data " + Yamlfilelocation + " --epochs " + string(app.EpochsEditField.Value) + ...
                    " --weights " + modelsize + " --batch-size " + string(app.BatchSizeEditField.Value) + " --img " + varImageSZ + " --device " + string(processorSize) + ...
                    " --workers 0 "; % Training command
     
            
            else
                if ~(app.ProjectFilepathEditField.Value == "0") && ~(isempty(app.ProjectFilepathEditField.Value)) % If a volume mount filepath is provided
                    textGenerate1 = "docker run -it --ipc=host --gpus all -v " + string(app.ProjectFilepathEditField.Value) +"\data:/ultralytics/data -v "+string(app.ProjectFilepathEditField.Value)+"\runs:/ultralytics/runs $t ndiamond1/yolov5artiamasimage:v1"; % Create a new docker container with that volume
                    textGenerate2 = "python train.py --data " + Yamlfilelocation + " --epochs " + string(app.EpochsEditField.Value) + ...
                        " --weights " + modelsize + " --batch-size " + string(app.BatchSizeEditField.Value) + " --img " + varImageSZ + " --device " + string(processorSize) + ...
                        " --workers 0"; % Training Command
                else
                    textGenerate1 = "docker run -it --ipc=host --gpus all $t ndiamond1/yolov5artiamasimage:v1"; % If no Volume mount provided, creates a new container with no volumes.
                    textGenerate2 =  "python train.py --data " + Yamlfilelocation + " --epochs " + string(app.EpochsEditField.Value) + ...
                        " --weights " + modelsize + " --batch-size " + string(app.BatchSizeEditField.Value) + " --img " + varImageSZ + " --device " + string(processorSize) + ...
                        " --workers 0"; % Training Command
                end
            end
            app.DockerCreateEnterEditField.Value = textGenerate1; % Displays to User in app
            app.DockerTrainCodeEditField.Value = textGenerate2; % Displays to User in app
        end

        % Callback function
        function ChooseVolumeDirectoryButtonPushed(app, event)
            t = []; % Initialize temp variable
            if ~(app.VolumeMountEditField.Value)
                t = app.VolumeMountEditField.Value; % Stores current value as backup into temp variable
            end
            location = string((app.ProjectFilepathEditField.Value))+ "\"; % Fixes the string
            TrainingDirectory = uigetdir(location); % UI window to get the directory where the training images are being stored
            TrainingDirectory2 = string(TrainingDirectory); % Creates variable for testing if UI was canceled
            if ~(TrainingDirectory2 == "0")
                app.VolumeMountEditField.Value = string(TrainingDirectory); % Displays filepath in Training Images Filepath text field back in App
                app.VolumeMountEditField_3.Value = string(TrainingDirectory); % Displays filepath in Training Images Filepath text field back in App
                app.VolumeMountEditField_5.Value = string(TrainingDirectory); % Displays filepath in Training Images Filepath text field back in App
                app.VolumeMountEditField_6.Value = string(TrainingDirectory); % Displays filepath in Training Images Filepath text field back in App


            elseif (TrainingDirectory2 == "0") && ~(isempty(t))
                app.VolumeMountEditField.Value = t; % Sets various volume mount display fields back to their previous value.
                app.VolumeMountEditField_3.Value = t;
                app.VolumeMountEditField_5.Value = t;
                app.VolumeMountEditField_6.Value = t;


            end
        end

        % Callback function
        function ExtractRunFromContainerButtonPushed(app, event)
            TextGenerate = "cp -r /ultralytics/runs/train/exp" + string(app.RunEditField.Value) + " /ultralytics/Volumes/Output/Train"; % Script line that copys over a directory to another location.
            app.ExtractCodeEditField.Value = TextGenerate;
        end

        % Button pushed function: DockerTestButton_2
        function DockerTestButtonPushed(app, event)
            %% This function generates the code for testing a model that is displayed back to the user to be modified and pasted into Powershell.
            
            % This splits the YAML filepath to find the relative route
            % from the beginning of the project directory, not the absolute
            % route


            % if (string(app.ModelWeightsRunEditField.Value) == "0") % If its the first training run, which has a different naming structure than the rest
            %     weights = "/ultralytics/runs/train/exp/weights/best.pt";
            % else % For all later training runs
            %     weights = "/ultralytics/runs/train/exp" + string(app.ModelWeightsRunEditField.Value)+"/weights/best.pt";
            % end
            % Yamlfilelocation = "/ultralytics/data/"+ string(app.YamlFileNameEditField.Value); % Creates the filepath for the yaml file to the new container file structure
            % TestDataFileLocation = "/ultralytics/data/" + string(app.TestDataFolderNameInContainerEditField.Value); % Creates the filepath for the container file structure

            newStr = split(app.YAMLFileFilepathEditField.Value, "\");
            Yamlfilelocation = newStr(end); % Grabs just the name of the YAML file.
            weights = ""; % Intialize Variable
            TestDataFileLocation = ""; % Intialize Variable

            % This splits the test data filepath for the same reason as
            % above
            newStr2 = split(app.TestDataFilepathEditField.Value,"\");
            if ~(isempty(newStr2{1})) % Protection against missing the test data filepath, only runs if its populated. 
                TestDataFileLocation = newStr2(end-1:end); % Finds the relative route inside the project, if folders are changed this will break. 
                TestDataFileLocation2 = ""; % Initializes the Testdata working value, overall not important
                for s = 1:size(TestDataFileLocation) % This for loop rebuilds the test data filepath in the correct format from its current split state
                    if s == 1
                        TestDataFileLocation2 = TestDataFileLocation2 + string(TestDataFileLocation(s)); % If its the first one, simply take the first directory
                    else
                        TestDataFileLocation2 = TestDataFileLocation2 + "/" + string(TestDataFileLocation(s)); % Add directories in order to the working value
                    end
                    s = s + 1; % Counter Variable
    
                end
                TestDataFileLocation = "/ultralytics/" + TestDataFileLocation2; %replaces final value with new filepath string
            else
                alert = warndlg("Missing Test Image Filepath, will not work", "WARNING");
            end

            % This splits the weights filepath for the same reason as above
            newStr3 = split(app.ModelWeightsFilepathEditField.Value  , "\"); % Splits filepath by slash
            if ~(isempty(newStr3{1}))
                weights = newStr3(end-4:end); % This is internal to the structure of the project, should folders be moved this will need to be updated
                weights2 = ""; % Initializes interim weights variable
                for s = 1:size(weights) % For loop for every string inside the split filepath
                    if s == 1
                        weights2 = weights2 + string(weights(s)); % If first value, simply take base route
                    else
                        weights2 = weights2 + "/" + string(weights(s)); % Every value after requires the slash
                    end
                    s = s + 1; % Counter Variable
    
                end
                weights = "/ultralytics/" + weights2; % Final Variable takes on interim variable's value
            else
                alert = warndlg("Missing Weights Filepath, will not work", "WARNING");
            end
            if ~(app.ContainerIDEditField_3.Value == "0") && ~(isempty(app.ContainerIDEditField_3.Value)) % If the container ID text field is not empty or blank
                
                textGenerate1 = "docker start "+ string(app.ContainerIDEditField_3.Value)+"; docker exec -it -u root "+ string(app.ContainerIDEditField_3.Value)+" sh"; % Start previous container by ID, then enter into container with root access and terminal
     
            
            else
                if ~(app.ProjectFilepathEditField.Value == "0") && ~(isempty(app.ProjectFilepathEditField.Value)) % If a volume mount is provided
                    textGenerate1 = "docker run -it --ipc=host --gpus all -v " + string(app.ProjectFilepathEditField.Value) +"\data:/ultralytics/data -v " + string(app.ProjectFilepathEditField.Value) +"\runs:/ultralytics/runs $t ndiamond1/yolov5artiamasimage:v1"; % Creates a new docker container with that volume mount
                else
                    textGenerate1 = "docker run -it --ipc=host --gpus all $t ndiamond1/yolov5artiamasimage:v1"; % Creates a new docker container with no volume mount.
                end
            end
            textGenerate2 =  "python detect.py --weights " + weights + " --data " + Yamlfilelocation + " --source " +  TestDataFileLocation + " --img " + string(app.ImageSizeEditField_2.Value); % Detection Code for Ultralytics Yolov5 python file
            app.DockerCreateEnterEditField_3.Value = textGenerate1; % Displays script line to User in App.
            app.DockerTestCodeEditField_2.Value = textGenerate2; % Displays script line to User in App.


        end

        % Callback function
        function ExtractRunFromContainerButton_3Pushed(app, event)
            TextGenerate = "cp -r /ultralytics/runs/detect/exp" + string(app.RunEditField_3.Value) + " /ultralytics/Volumes/Output/Detect"; % Copys directory from internal structure to the Volume mount for easy access on local filesystem
            app.ExtractCodeEditField_3.Value = TextGenerate; % Displays script line to User in App.
        end

        % Callback function
        function ExtractRunFromContainerButton_5Pushed(app, event)
            TextGenerate = "cp -r /ultralytics/runs/train/exp" + string(app.RunEditField_5.Value) + " /ultralytics/Volumes/Output/Train";  % Copys directory from internal structure to the Volume mount for easy access on local filesystem
            app.ExtractCodeEditField_5.Value = TextGenerate; % Displays script line to User in App.
        end

        % Button pushed function: DockerExportButton
        function DockerExportButtonPushed(app, event)
             %% This function generates the code for testing a model that is displayed back to the user to be modified and pasted into Powershell.
            
       
            % if (string(app.ModelWeightsRunEditField_2.Value) == "0") % If attempting to export the first training run, change structure with internal syntax
            %     weights = "/ultralytics/runs/train/exp/weights/best.pt";
            % else
            %     weights = "/ultralytics/runs/train/exp" + string(app.ModelWeightsRunEditField_2.Value)+"/weights/best.pt"; % for all other runs, generate filepath structure
            % end
            if ~(app.ModelWeightsFilepathEditField.Value == "0") && ~(isempty(app.ModelWeightsFilepathEditField.Value)) % Checks to see if the Weights filepath isnt empty or unintialized. 

                newStr3 = split(app.ModelWeightsFilepathEditField.Value , "\"); %Splits the weights filepath into sections based on the slash
                weights = newStr3(end-4:end); % Finds the weights filepath based on the base project directory, if folders are moved this will need to be updated
                weights2 = ""; % Initializes the interim temp variable, overall not important. 
                for s = 1:size(weights) % For loop for length of how many sections are in the split string
                    if s == 1
                        weights2 = weights2 + string(weights(s)); % For the first section, keep normal.
                    else
                        weights2 = weights2 + "/" + string(weights(s)); % For every section following, add the slashes for formatting
                    end
                    s = s + 1; % Counter variable
    
                end
                weights = "/ultralytics/"+weights2; % Transfer weights filepath value from iterim variable to final variable
            else
                alert = warndlg("WEIGHTS FILEPATH MISSING", "INVALID ENTRY"); % Alert user that this function will fail without having the filepath set
            end

            if ~(string(app.ContainerIDEditField_5.Value) == "0") && ~(isempty(app.ContainerIDEditField_5.Value)) %
                
                textGenerate1 = "docker start "+ string(app.ContainerIDEditField_5.Value)+"; docker exec -it -u root "+ string(app.ContainerIDEditField_5.Value)+" sh"; % Start previous container by ID, then enter into container with root access and terminal
     
            
            else
                if ~(app.ProjectFilepathEditField.Value == "0") && ~(isempty(app.ProjectFilepathEditField.Value))  % If a volume mount is provided
                    textGenerate1 = "docker run -it --ipc=host --gpus all -v " + string(app.ProjectFilepathEditField.Value) +"\data:/ultralytics/data -v " + string(app.ProjectFilepathEditField.Value) +"\runs:/ultralytics/runs $t ndiamond1/yolov5artiamasimage:v1";  % Creates a new docker container with that volume mount
                else  % If no volume mount is provided
                    textGenerate1 = "docker run -it --ipc=host --gpus all $t ndiamond1/yolov5artiamasimage:v1";  % Creates a new docker container with no volume mount.
                end
            end
            textGenerate2 =  "python export.py --img "+string(app.ImageSizeEditField_3.Value)+" --weights "+weights+" --include tflite"; % Export Code for Ultralytics Yolov5 python file
            app.DockerCreateEnterEditField_5.Value = textGenerate1; % Displays script line to User in App.
            app.DockerExportCodeEditField.Value = textGenerate2; % Displays script line to User in App.

        end

        % Callback function
        function DockerStartButtonPushed(app, event)
            if ~(app.VolumeMountEditField.Value == "0") && ~(isempty(app.VolumeMountEditField.Value))  % If a volume mount is provided

                textGenerate1 = "docker run -it --ipc=host --gpus all -v " + string(app.VolumeMountEditField.Value) +":/ultralytics/Volumes $t ndiamond1/yolov5artiamasimage:v1"; % Creates a new docker container with a volume mount selected
            else
                alert = warndlg("CHOOSE VOLUME LOCATION", "INVALID ENTRY");
            end
            app.DockerCreateEnterEditField_6.Value = textGenerate1; % Displays script line to User in App.
            alert = warndlg("RECORD CONTAINER ID", "REMINDER");


        end

        % Callback function
        function MoveDataButtonPushed(app, event)
            %% This function sets the input directory for the initial training/validation images
            
            location = string((app.ProjectFilepathEditField.Value))+ "/Volumes/Input/"; % Fixes the string
            TrainingDirectory = uigetdir(location); % UI window to get the directory where the training images are being stored
            TrainingDirectory2 = string(TrainingDirectory);
            newStr = split(TrainingDirectory2, "\"); % Split the filepath into chunks
            newStr2 = newStr(end); % The relative path from the project working directory
            newStr3 = newStr2{1}; % Taking it out of the cell container
            if ~(TrainingDirectory2 == "0")
                TextGenerate = "cp -r /ultralytics/Volumes/Input/" + newStr3 + " /ultralytics/data/"; % generates line to copy directory from volume input directly into data directories for use by Train.py
                app.PasteThisIntoDockerShellEditField.Value = TextGenerate; % Displays script line to User in App.

            end


        end

        % Callback function
        function MoveContentstoVolumeButtonPushed(app, event)
            % if ~(isempty(app.TrainingTestingImagesFilepathEditField.Value)) && ~(isempty(app.VolumeMountEditField.Value)) % If both a data filepath has been provided and has access to a volume mount
            %     system('start powershell -NoExit -Command "cp -r ' + string(app.TrainingTestingImagesFilepathEditField.Value)+' '+ string(app.VolumeMountEditField.Value)+'\Input"') % Open powershell and copy the data into the volume mount on the local filesystem
            % else
            %     alert = warndlg("Missing Either Training Image Filepath and/or Volume Mount Filepath", "INVALID ENTRY");
            % 
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProjectSetUpTab = uitab(app.TabGroup);
            app.ProjectSetUpTab.Title = 'Project Set Up';
            app.SetProjectPointerButton = uibutton(app.ProjectSetUpTab, 'push');
            app.SetProjectPointerButton.ButtonPushedFcn = createCallbackFcn(app, @SetProjectPointerButtonPushed, true);
            app.SetProjectPointerButton.Text = 'Set Project Pointer';
            app.ProjectFilepathEditFieldLabel = uilabel(app.ProjectSetUpTab);
            app.ProjectFilepathEditFieldLabel.Text = 'Project Filepath';
            app.ProjectFilepathEditField = uieditfield(app.ProjectSetUpTab, 'text');
            app.DataInputTab = uitab(app.TabGroup);
            app.DataInputTab.Title = 'Data Input';
            app.TabGroup5 = uitabgroup(app.DataInputTab);
            app.LocalTab = uitab(app.TabGroup5);
            app.LocalTab.Title = 'Local';
            app.FirstPointtheApptowardstheDatayouwantPanel = uipanel(app.LocalTab);
            app.FirstPointtheApptowardstheDatayouwantPanel.Title = 'First, Point the App towards the Data you want ';
            app.SetInputDataDirectoryButton = uibutton(app.FirstPointtheApptowardstheDatayouwantPanel, 'push');
            app.SetInputDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetInputDataDirectoryButtonPushed, true);
            app.SetInputDataDirectoryButton.Text = 'Set Input Data Directory';
            app.ImagesFilepathEditFieldLabel = uilabel(app.FirstPointtheApptowardstheDatayouwantPanel);
            app.ImagesFilepathEditFieldLabel.Text = 'Images Filepath';
            app.ImagesFilepathEditField = uieditfield(app.FirstPointtheApptowardstheDatayouwantPanel, 'text');
            app.SecondCreateInputDataFoldersPanel = uipanel(app.LocalTab);
            app.SecondCreateInputDataFoldersPanel.Title = 'Second, Create Input Data Folders';
            app.CreateFoldersButton = uibutton(app.SecondCreateInputDataFoldersPanel, 'push');
            app.CreateFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFoldersButtonPushed, true);
            app.CreateFoldersButton.Text = 'Create Folders';
            app.FolderLocationEditFieldLabel = uilabel(app.SecondCreateInputDataFoldersPanel);
            app.FolderLocationEditFieldLabel.Text = 'Folder Location';
            app.FolderLocationEditField = uieditfield(app.SecondCreateInputDataFoldersPanel, 'text');
            app.NameofFolderEditFieldLabel = uilabel(app.SecondCreateInputDataFoldersPanel);
            app.NameofFolderEditFieldLabel.Text = 'Name of Folder';
            app.NameofFolderEditField = uieditfield(app.SecondCreateInputDataFoldersPanel, 'text');
            app.THISWILLDELETEEXISTINGFOLDERSTOOVERWRITEMAKESUREITSCORRECTLabel = uilabel(app.SecondCreateInputDataFoldersPanel);
            app.THISWILLDELETEEXISTINGFOLDERSTOOVERWRITEMAKESUREITSCORRECTLabel.Text = {'THIS WILL DELETE EXISTING'; ' FOLDERS TO OVERWRITE,'; ' MAKE SURE ITS CORRECT'};
            app.ThirdPointtheApptowardstheYAMLFilePanel = uipanel(app.LocalTab);
            app.ThirdPointtheApptowardstheYAMLFilePanel.Title = 'Third, Point the App towards the YAML File';
            app.YAMLFileFilepathEditFieldLabel = uilabel(app.ThirdPointtheApptowardstheYAMLFilePanel);
            app.YAMLFileFilepathEditFieldLabel.Text = 'YAML File Filepath';
            app.YAMLFileFilepathEditField = uieditfield(app.ThirdPointtheApptowardstheYAMLFilePanel, 'text');
            app.SetYAMLFileFilepathButton = uibutton(app.ThirdPointtheApptowardstheYAMLFilePanel, 'push');
            app.SetYAMLFileFilepathButton.ButtonPushedFcn = createCallbackFcn(app, @SetYAMLFileFilepathButtonPushed, true);
            app.SetYAMLFileFilepathButton.Text = 'Set YAML File Filepath';
            app.TrainModelTab = uitab(app.TabGroup);
            app.TrainModelTab.Title = 'Train Model';
            app.TabGroup4 = uitabgroup(app.TrainModelTab);
            app.LocalTab_2 = uitab(app.TabGroup4);
            app.LocalTab_2.Title = 'Local';
            app.TabGroup10 = uitabgroup(app.LocalTab_2);
            app.ManualTab_4 = uitab(app.TabGroup10);
            app.ManualTab_4.Title = 'Manual';
            app.OpenPowershellButton = uibutton(app.ManualTab_4, 'push');
            app.OpenPowershellButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPowershellButtonPushed, true);
            app.OpenPowershellButton.Text = 'Open Powershell';
            app.GenerateCodeButton = uibutton(app.ManualTab_4, 'push');
            app.GenerateCodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCodeButtonPushed, true);
            app.GenerateCodeButton.Text = 'Generate Code';
            app.PastethisintopowershellLabel = uilabel(app.ManualTab_4);
            app.PastethisintopowershellLabel.Text = 'Paste this into powershell!';
            app.CodeEditFieldLabel = uilabel(app.ManualTab_4);
            app.CodeEditFieldLabel.Text = 'Code';
            app.CodeEditField = uieditfield(app.ManualTab_4, 'text');
            app.AutomaticTab_4 = uitab(app.TabGroup10);
            app.AutomaticTab_4.Title = 'Automatic';
            app.TRAINMODELButton = uibutton(app.AutomaticTab_4, 'push');
            app.TRAINMODELButton.ButtonPushedFcn = createCallbackFcn(app, @TRAINMODELButtonPushed, true);
            app.TRAINMODELButton.Text = 'TRAIN MODEL';
            app.DockerTab = uitab(app.TabGroup4);
            app.DockerTab.Title = 'Docker';
            app.IfStartingPreviousContainerOptionalPanel = uipanel(app.DockerTab);
            app.IfStartingPreviousContainerOptionalPanel.Title = 'If Starting Previous Container (Optional)';
            app.aIfalreadyexistingContainerPanel = uipanel(app.IfStartingPreviousContainerOptionalPanel);
            app.aIfalreadyexistingContainerPanel.Title = '1a. If already existing Container';
            app.ContainerIDEditFieldLabel = uilabel(app.aIfalreadyexistingContainerPanel);
            app.ContainerIDEditFieldLabel.Text = 'Container ID';
            app.ContainerIDEditField = uieditfield(app.aIfalreadyexistingContainerPanel, 'text');
            app.cOpenPowershellPanel = uipanel(app.IfStartingPreviousContainerOptionalPanel);
            app.cOpenPowershellPanel.Title = '1c. Open Powershell ';
            app.OpenPowershellButton_4 = uibutton(app.cOpenPowershellPanel, 'push');
            app.OpenPowershellButton_4.ButtonPushedFcn = createCallbackFcn(app, @OpenPowershellButtonPushed, true);
            app.OpenPowershellButton_4.Text = 'Open Powershell';
            app.CreateCodeforTrainingInDockerContainerPanel = uipanel(app.DockerTab);
            app.CreateCodeforTrainingInDockerContainerPanel.Title = '2. Create Code for Training In Docker Container';
            app.OpenDockerContainerandTrainPanel = uipanel(app.CreateCodeforTrainingInDockerContainerPanel);
            app.OpenDockerContainerandTrainPanel.Title = '2. Open Docker Container and Train';
            app.DockerTrainButton = uibutton(app.OpenDockerContainerandTrainPanel, 'push');
            app.DockerTrainButton.ButtonPushedFcn = createCallbackFcn(app, @DockerTrainButtonPushed, true);
            app.DockerTrainButton.Text = 'Docker Train';
            app.ExecutionStep2Panel = uipanel(app.CreateCodeforTrainingInDockerContainerPanel);
            app.ExecutionStep2Panel.Title = 'Execution Step 2';
            app.DockerTrainCodeEditFieldLabel = uilabel(app.ExecutionStep2Panel);
            app.DockerTrainCodeEditFieldLabel.Text = 'Docker Train Code';
            app.DockerTrainCodeEditField = uieditfield(app.ExecutionStep2Panel, 'text');
            app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel = uipanel(app.CreateCodeforTrainingInDockerContainerPanel);
            app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel.Title = 'Execution Step 1 (ONLY DO IF DOCKER CONTAINER NOT ALREADY RUNNING)';
            app.DockerCreateEnterEditFieldLabel = uilabel(app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel);
            app.DockerCreateEnterEditFieldLabel.Text = 'Docker Create/Enter';
            app.DockerCreateEnterEditField = uieditfield(app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel, 'text');
            app.DONOTHAVEANYYAMLFILESWITHTHESAMENAMEINTHECONTAINERALREADYLabel = uilabel(app.CreateCodeforTrainingInDockerContainerPanel);
            app.DONOTHAVEANYYAMLFILESWITHTHESAMENAMEINTHECONTAINERALREADYLabel.Text = 'DO NOT HAVE ANY YAML FILES WITH THE SAME NAME IN THE CONTAINER ALREADY';
            app.ModelSizeListBoxLabel = uilabel(app.TrainModelTab);
            app.ModelSizeListBoxLabel.Text = 'Model Size';
            app.ModelSizeListBox = uilistbox(app.TrainModelTab);
            app.ModelSizeListBox.Items = {'Nano', 'Small', 'Medium', 'Large', 'Extra Large'};
            app.ModelSizeListBox.Value = 'Nano';
            app.EpochsLabel = uilabel(app.TrainModelTab);
            app.EpochsLabel.Text = 'Epochs ';
            app.EpochsEditField = uieditfield(app.TrainModelTab, 'numeric');
            app.EpochsEditField.Limits = [1 Inf];
            app.EpochsEditField.RoundFractionalValues = 'on';
            app.EpochsEditField.Value = 1;
            app.BatchSizeEditFieldLabel = uilabel(app.TrainModelTab);
            app.BatchSizeEditFieldLabel.Text = 'Batch Size';
            app.BatchSizeEditField = uieditfield(app.TrainModelTab, 'numeric');
            app.BatchSizeEditField.Limits = [1 Inf];
            app.BatchSizeEditField.RoundFractionalValues = 'on';
            app.BatchSizeEditField.Value = 1;
            app.ImageSizeEditFieldLabel = uilabel(app.TrainModelTab);
            app.ImageSizeEditFieldLabel.Text = 'Image Size';
            app.ImageSizeEditField = uieditfield(app.TrainModelTab, 'numeric');
            app.ImageSizeEditField.Limits = [1 Inf];
            app.ImageSizeEditField.RoundFractionalValues = 'on';
            app.ImageSizeEditField.Value = 1;
            app.RECORDTHERUNATTHEENDTHISISWHEREYOURRESULTSARESTOREDLabel = uilabel(app.TrainModelTab);
            app.RECORDTHERUNATTHEENDTHISISWHEREYOURRESULTSARESTOREDLabel.Text = {'RECORD THE RUN ## AT THE END'; 'THIS IS WHERE YOUR RESULTS ARE STORED'};
            app.RightMousetoCopyPasteintoDockerShellsLabel = uilabel(app.TrainModelTab);
            app.RightMousetoCopyPasteintoDockerShellsLabel.Text = 'Right Mouse to Copy/Paste into Docker Shells';
            app.ProcessorListBoxLabel = uilabel(app.TrainModelTab);
            app.ProcessorListBoxLabel.Text = 'Processor';
            app.ProcessorListBox = uilistbox(app.TrainModelTab);
            app.ProcessorListBox.Items = {'GPU', 'CPU'};
            app.ProcessorListBox.Value = 'GPU';
            app.TrainTimerTab = uitab(app.TabGroup);
            app.TrainTimerTab.Title = 'Train Timer';
            app.EpochTrainingTimeminEditFieldLabel = uilabel(app.TrainTimerTab);
            app.EpochTrainingTimeminEditFieldLabel.Text = 'Epoch Training Time (min)';
            app.EpochTrainingTimeminEditField = uieditfield(app.TrainTimerTab, 'numeric');
            app.EpochTrainingTimeminEditField.Limits = [0 10000];
            app.EpochTrainingTimeminEditField.RoundFractionalValues = 'on';
            app.EpochTrainingTimesEditFieldLabel = uilabel(app.TrainTimerTab);
            app.EpochTrainingTimesEditFieldLabel.Text = 'Epoch Training Time (s)';
            app.EpochTrainingTimesEditField = uieditfield(app.TrainTimerTab, 'numeric');
            app.EpochTrainingTimesEditField.Limits = [0 10000];
            app.EpochTrainingTimesEditField.RoundFractionalValues = 'on';
            app.EpochsLeftEditFieldLabel = uilabel(app.TrainTimerTab);
            app.EpochsLeftEditFieldLabel.Text = 'Epochs Left';
            app.EpochsLeftEditField = uieditfield(app.TrainTimerTab, 'numeric');
            app.EpochsLeftEditField.Limits = [0 10000];
            app.EpochsLeftEditField.RoundFractionalValues = 'on';
            app.CalculateButton = uibutton(app.TrainTimerTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.HoursLeftEditFieldLabel = uilabel(app.TrainTimerTab);
            app.HoursLeftEditFieldLabel.Text = 'Hours Left';
            app.HoursLeftEditField = uieditfield(app.TrainTimerTab, 'numeric');
            app.HoursLeftEditField.Editable = 'off';
            app.MinutesLeftEditFieldLabel = uilabel(app.TrainTimerTab);
            app.MinutesLeftEditFieldLabel.Text = 'Minutes Left';
            app.MinutesLeftEditField = uieditfield(app.TrainTimerTab, 'numeric');
            app.MinutesLeftEditField.Editable = 'off';
            app.SecondsLeftEditFieldLabel = uilabel(app.TrainTimerTab);
            app.SecondsLeftEditFieldLabel.Text = 'Seconds Left';
            app.SecondsLeftEditField = uieditfield(app.TrainTimerTab, 'numeric');
            app.SecondsLeftEditField.Editable = 'off';
            app.StartTimerButton = uibutton(app.TrainTimerTab, 'push');
            app.StartTimerButton.ButtonPushedFcn = createCallbackFcn(app, @StartTimerButtonPushed, true);
            app.StartTimerButton.Text = 'Start Timer';
            app.ModelwillfinishtrainingatEditFieldLabel = uilabel(app.TrainTimerTab);
            app.ModelwillfinishtrainingatEditFieldLabel.Text = 'Model will finish training at';
            app.ModelwillfinishtrainingatEditField = uieditfield(app.TrainTimerTab, 'text');
            app.StopTimerButton = uibutton(app.TrainTimerTab, 'push');
            app.StopTimerButton.ButtonPushedFcn = createCallbackFcn(app, @StopTimerButtonPushed, true);
            app.StopTimerButton.Text = 'Stop Timer';
            app.TestModelTab = uitab(app.TabGroup);
            app.TestModelTab.Title = 'Test Model';
            app.TabGroup6 = uitabgroup(app.TestModelTab);
            app.TestingTab = uitab(app.TabGroup6);
            app.TestingTab.Title = 'Testing';
            app.GetWeightsFilepathButton = uibutton(app.TestingTab, 'push');
            app.GetWeightsFilepathButton.ButtonPushedFcn = createCallbackFcn(app, @GetWeightsFilepathButtonPushed, true);
            app.GetWeightsFilepathButton.Text = 'Get Weights Filepath';
            app.GetTestDataFilepathButton = uibutton(app.TestingTab, 'push');
            app.GetTestDataFilepathButton.ButtonPushedFcn = createCallbackFcn(app, @GetTestDataFilepathButtonPushed, true);
            app.GetTestDataFilepathButton.Text = 'Get Test Data Filepath';
            app.GetYAMLFilepathButton = uibutton(app.TestingTab, 'push');
            app.GetYAMLFilepathButton.ButtonPushedFcn = createCallbackFcn(app, @SetYAMLFileFilepathButtonPushed, true);
            app.GetYAMLFilepathButton.Text = 'Get YAML Filepath';
            app.ModelWeightsFilepathLabel = uilabel(app.TestingTab);
            app.ModelWeightsFilepathLabel.Text = 'Model Weights Filepath';
            app.ModelWeightsFilepathEditField = uieditfield(app.TestingTab, 'text');
            app.TestDataFilepathEditFieldLabel = uilabel(app.TestingTab);
            app.TestDataFilepathEditFieldLabel.Text = 'Test Data Filepath';
            app.TestDataFilepathEditField = uieditfield(app.TestingTab, 'text');
            app.ImageSizeEditField_2Label = uilabel(app.TestingTab);
            app.ImageSizeEditField_2Label.Text = 'Image Size';
            app.ImageSizeEditField_2 = uieditfield(app.TestingTab, 'numeric');
            app.ImageSizeEditField_2.Limits = [1 Inf];
            app.ImageSizeEditField_2.RoundFractionalValues = 'on';
            app.ImageSizeEditField_2.Value = 1;
            app.YAMLFilepathEditFieldLabel = uilabel(app.TestingTab);
            app.YAMLFilepathEditFieldLabel.Text = 'YAML Filepath';
            app.YAMLFilepathEditField = uieditfield(app.TestingTab, 'text');
            app.TabGroup4_2 = uitabgroup(app.TestingTab);
            app.ManualTab_3 = uitab(app.TabGroup4_2);
            app.ManualTab_3.Title = 'Manual';
            app.ManualTestingPanel = uipanel(app.ManualTab_3);
            app.ManualTestingPanel.Title = 'Manual Testing';
            app.OpenPowershellButton_2 = uibutton(app.ManualTestingPanel, 'push');
            app.OpenPowershellButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenPowershellButton_2Pushed, true);
            app.OpenPowershellButton_2.Text = 'Open Powershell';
            app.GenerateCodeButton_2 = uibutton(app.ManualTestingPanel, 'push');
            app.GenerateCodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateCodeButton_2Pushed, true);
            app.GenerateCodeButton_2.Text = 'Generate Code!';
            app.PasteThisInEditFieldLabel = uilabel(app.ManualTestingPanel);
            app.PasteThisInEditFieldLabel.Text = 'Paste This In';
            app.PasteThisInEditField = uieditfield(app.ManualTestingPanel, 'text');
            app.Label = uilabel(app.ManualTestingPanel);
            app.Label.Text = 'Note: Record the number in runs\detect\exp##, this is where the results are outputed.';
            app.AutomaticTab_3 = uitab(app.TabGroup4_2);
            app.AutomaticTab_3.Title = 'Automatic';
            app.AutomaticTestingPanel = uipanel(app.AutomaticTab_3);
            app.AutomaticTestingPanel.Title = 'Automatic Testing';
            app.TESTMODELButton = uibutton(app.AutomaticTestingPanel, 'push');
            app.TESTMODELButton.ButtonPushedFcn = createCallbackFcn(app, @TESTMODELButtonPushed, true);
            app.TESTMODELButton.Text = 'TEST MODEL';
            app.DockerTab_7 = uitab(app.TabGroup4_2);
            app.DockerTab_7.Title = 'Docker';
            app.aIfalreadyexistingContainerPanel_3 = uipanel(app.DockerTab_7);
            app.aIfalreadyexistingContainerPanel_3.Title = '1a. If already existing Container';
            app.ContainerIDEditField_3Label = uilabel(app.aIfalreadyexistingContainerPanel_3);
            app.ContainerIDEditField_3Label.Text = 'Container ID';
            app.ContainerIDEditField_3 = uieditfield(app.aIfalreadyexistingContainerPanel_3, 'text');
            app.OpenDockerContainerandTrainPanel_3 = uipanel(app.DockerTab_7);
            app.OpenDockerContainerandTrainPanel_3.Title = '2. Open Docker Container and Train';
            app.DockerTestButton_2 = uibutton(app.OpenDockerContainerandTrainPanel_3, 'push');
            app.DockerTestButton_2.ButtonPushedFcn = createCallbackFcn(app, @DockerTestButtonPushed, true);
            app.DockerTestButton_2.Text = 'Docker Test';
            app.OpenPowershellButton_7 = uibutton(app.OpenDockerContainerandTrainPanel_3, 'push');
            app.OpenPowershellButton_7.ButtonPushedFcn = createCallbackFcn(app, @OpenPowershellButtonPushed, true);
            app.OpenPowershellButton_7.Text = 'Open Powershell';
            app.ExecutionStep2Panel_3 = uipanel(app.DockerTab_7);
            app.ExecutionStep2Panel_3.Title = 'Execution Step 2';
            app.DockerTestCodeEditField_2Label = uilabel(app.ExecutionStep2Panel_3);
            app.DockerTestCodeEditField_2Label.Text = 'Docker Test Code';
            app.DockerTestCodeEditField_2 = uieditfield(app.ExecutionStep2Panel_3, 'text');
            app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_2 = uipanel(app.DockerTab_7);
            app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_2.Title = 'Execution Step 1 (ONLY DO IF DOCKER CONTAINER NOT ALREADY RUNNING)';
            app.DockerCreateEnterEditField_3Label = uilabel(app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_2);
            app.DockerCreateEnterEditField_3Label.Text = 'Docker Create/Enter';
            app.DockerCreateEnterEditField_3 = uieditfield(app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_2, 'text');
            app.RightMousetoCopyPasteintoDockerShellsLabel_3 = uilabel(app.DockerTab_7);
            app.RightMousetoCopyPasteintoDockerShellsLabel_3.Text = 'Right Mouse to Copy/Paste into Docker Shells';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.TabGroup2 = uitabgroup(app.AnalysisTab);
            app.DetectionMetricsTab = uitab(app.TabGroup2);
            app.DetectionMetricsTab.Title = 'Detection Metrics';
            app.UITable = uitable(app.DetectionMetricsTab);
            app.UITable.ColumnName = {'#'; 'Detections'; 'TP'; 'FP'; 'FN'};
            app.UITable.RowName = {};
            app.TotalTruePositivesEditFieldLabel = uilabel(app.DetectionMetricsTab);
            app.TotalTruePositivesEditFieldLabel.Text = 'Total True Positives';
            app.TotalTruePositivesEditField = uieditfield(app.DetectionMetricsTab, 'numeric');
            app.TotalTruePositivesEditField.Editable = 'off';
            app.TotalFalsePositivesEditFieldLabel = uilabel(app.DetectionMetricsTab);
            app.TotalFalsePositivesEditFieldLabel.Text = 'Total False Positives';
            app.TotalFalsePositivesEditField = uieditfield(app.DetectionMetricsTab, 'numeric');
            app.TotalFalsePositivesEditField.Editable = 'off';
            app.DetectionRateEditFieldLabel = uilabel(app.DetectionMetricsTab);
            app.DetectionRateEditFieldLabel.Text = 'Detection Rate';
            app.DetectionRateEditField = uieditfield(app.DetectionMetricsTab, 'numeric');
            app.DetectionRateEditField.Editable = 'off';
            app.ExportTableButton = uibutton(app.DetectionMetricsTab, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.AddEntryPanel = uipanel(app.DetectionMetricsTab);
            app.AddEntryPanel.Title = 'Add Entry';
            app.DetectionsEditFieldLabel = uilabel(app.AddEntryPanel);
            app.DetectionsEditFieldLabel.Text = 'Detections';
            app.DetectionsEditField = uieditfield(app.AddEntryPanel, 'numeric');
            app.DetectionsEditField.Limits = [0 10000];
            app.DetectionsEditField.RoundFractionalValues = 'on';
            app.TruePositivesEditFieldLabel = uilabel(app.AddEntryPanel);
            app.TruePositivesEditFieldLabel.Text = 'True Positives';
            app.TruePositivesEditField = uieditfield(app.AddEntryPanel, 'numeric');
            app.TruePositivesEditField.Limits = [0 10000];
            app.TruePositivesEditField.RoundFractionalValues = 'on';
            app.FalsePositivesEditFieldLabel = uilabel(app.AddEntryPanel);
            app.FalsePositivesEditFieldLabel.Text = 'False Positives';
            app.FalsePositivesEditField = uieditfield(app.AddEntryPanel, 'numeric');
            app.FalsePositivesEditField.Limits = [0 10000];
            app.FalsePositivesEditField.RoundFractionalValues = 'on';
            app.FalseNegativesEditFieldLabel = uilabel(app.AddEntryPanel);
            app.FalseNegativesEditFieldLabel.Text = 'False Negatives';
            app.FalseNegativesEditField = uieditfield(app.AddEntryPanel, 'numeric');
            app.FalseNegativesEditField.Limits = [0 10000];
            app.FalseNegativesEditField.RoundFractionalValues = 'on';
            app.AddEntryButton = uibutton(app.AddEntryPanel, 'push');
            app.AddEntryButton.ButtonPushedFcn = createCallbackFcn(app, @AddEntryButtonPushed, true);
            app.AddEntryButton.Text = 'Add Entry';
            app.TotalObjectsPresentEditFieldLabel = uilabel(app.DetectionMetricsTab);
            app.TotalObjectsPresentEditFieldLabel.Text = 'Total Objects Present';
            app.TotalObjectsPresentEditField = uieditfield(app.DetectionMetricsTab, 'numeric');
            app.TotalObjectsPresentEditField.Editable = 'off';
            app.GenerateMetricsButton = uibutton(app.DetectionMetricsTab, 'push');
            app.GenerateMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMetricsButtonPushed, true);
            app.GenerateMetricsButton.Text = 'Generate Metrics';
            app.ClearTableButton = uibutton(app.DetectionMetricsTab, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.RemoveEntryPanel = uipanel(app.DetectionMetricsTab);
            app.RemoveEntryPanel.Title = 'Remove Entry';
            app.RowEditFieldLabel = uilabel(app.RemoveEntryPanel);
            app.RowEditFieldLabel.Text = 'Row #';
            app.RowEditField = uieditfield(app.RemoveEntryPanel, 'numeric');
            app.RowEditField.Limits = [1 Inf];
            app.RowEditField.RoundFractionalValues = 'on';
            app.RowEditField.Value = 1;
            app.RemoveEntryButton = uibutton(app.RemoveEntryPanel, 'push');
            app.RemoveEntryButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEntryButtonPushed, true);
            app.RemoveEntryButton.Text = 'Remove Entry';
            app.ExportMetricstoTxtButton = uibutton(app.DetectionMetricsTab, 'push');
            app.ExportMetricstoTxtButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMetricstoTxtButtonPushed, true);
            app.ExportMetricstoTxtButton.Text = 'Export Metrics to Txt';
            app.LoadTableButton = uibutton(app.DetectionMetricsTab, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = 'Load Table';
            app.ConfidenceLevelsTab = uitab(app.TabGroup2);
            app.ConfidenceLevelsTab.Title = 'Confidence Levels';
            app.UITable2 = uitable(app.ConfidenceLevelsTab);
            app.UITable2.ColumnName = {'#'; 'TP CLs'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.ColumnEditable = true;
            app.UITable2_2 = uitable(app.ConfidenceLevelsTab);
            app.UITable2_2.ColumnName = {'#'; 'FP CLs'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnSortable = true;
            app.UITable2_2.ColumnEditable = true;
            app.CLThresholdSliderLabel = uilabel(app.ConfidenceLevelsTab);
            app.CLThresholdSliderLabel.Text = {'CL '; 'Threshold'};
            app.CLThresholdSlider = uislider(app.ConfidenceLevelsTab);
            app.CLThresholdSlider.Limits = [0 1];
            app.CLThresholdSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CLThresholdSlider.MajorTickLabels = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1'};
            app.CLThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @CLThresholdSliderValueChanging, true);
            app.CLThresholdLabel = uilabel(app.ConfidenceLevelsTab);
            app.CLThresholdLabel.Text = {'CL '; 'Threshold'};
            app.CLThresholdEditField = uieditfield(app.ConfidenceLevelsTab, 'numeric');
            app.CLThresholdEditField.Limits = [0 1];
            app.CLThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @CLThresholdEditFieldValueChanged, true);
            app.TruePositivesRemainingEditFieldLabel = uilabel(app.ConfidenceLevelsTab);
            app.TruePositivesRemainingEditFieldLabel.Text = 'True Positives Remaining';
            app.TruePositivesRemainingEditField = uieditfield(app.ConfidenceLevelsTab, 'numeric');
            app.TruePositivesRemainingEditField.Editable = 'off';
            app.FalsePositivesRemainingEditFieldLabel = uilabel(app.ConfidenceLevelsTab);
            app.FalsePositivesRemainingEditFieldLabel.Text = 'False Positives Remaining';
            app.FalsePositivesRemainingEditField = uieditfield(app.ConfidenceLevelsTab, 'numeric');
            app.FalsePositivesRemainingEditField.Editable = 'off';
            app.AddCLEntryPanel = uipanel(app.ConfidenceLevelsTab);
            app.AddCLEntryPanel.Title = 'Add CL Entry';
            app.CLEditFieldLabel = uilabel(app.AddCLEntryPanel);
            app.CLEditFieldLabel.Text = 'CL';
            app.CLEditField = uieditfield(app.AddCLEntryPanel, 'numeric');
            app.CLEditField.Limits = [0 1];
            app.ButtonGroup = uibuttongroup(app.AddCLEntryPanel);
            app.TruePositiveButton = uiradiobutton(app.ButtonGroup);
            app.TruePositiveButton.Text = 'True Positive';
            app.TruePositiveButton.Value = true;
            app.FalsePositiveButton = uiradiobutton(app.ButtonGroup);
            app.FalsePositiveButton.Text = 'False Positive';
            app.AddEntryButton_2 = uibutton(app.AddCLEntryPanel, 'push');
            app.AddEntryButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddEntryButton_2Pushed, true);
            app.AddEntryButton_2.Text = 'Add Entry';
            app.RemoveCLEntryPanel = uipanel(app.ConfidenceLevelsTab);
            app.RemoveCLEntryPanel.Title = 'Remove CL Entry';
            app.RowEditField_2Label = uilabel(app.RemoveCLEntryPanel);
            app.RowEditField_2Label.Text = 'Row';
            app.RowEditField_2 = uieditfield(app.RemoveCLEntryPanel, 'numeric');
            app.DeleteTPButton = uibutton(app.RemoveCLEntryPanel, 'push');
            app.DeleteTPButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTPButtonPushed, true);
            app.DeleteTPButton.Text = 'Delete TP';
            app.DeleteFPButton = uibutton(app.RemoveCLEntryPanel, 'push');
            app.DeleteFPButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFPButtonPushed, true);
            app.DeleteFPButton.Text = 'Delete FP';
            app.TruePositivesPanel = uipanel(app.ConfidenceLevelsTab);
            app.TruePositivesPanel.Title = 'True Positives';
            app.SaveTPTableButton = uibutton(app.TruePositivesPanel, 'push');
            app.SaveTPTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTPTableButtonPushed, true);
            app.SaveTPTableButton.Text = 'Save TP Table';
            app.ClearTPTableButton = uibutton(app.TruePositivesPanel, 'push');
            app.ClearTPTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTPTableButtonPushed, true);
            app.ClearTPTableButton.Text = 'Clear TP Table';
            app.LoadTPTableButton = uibutton(app.TruePositivesPanel, 'push');
            app.LoadTPTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTPTableButtonPushed, true);
            app.LoadTPTableButton.Text = 'Load TP Table';
            app.FalsePositivesPanel = uipanel(app.ConfidenceLevelsTab);
            app.FalsePositivesPanel.Title = 'False Positives';
            app.SaveFPTableButton = uibutton(app.FalsePositivesPanel, 'push');
            app.SaveFPTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFPTableButtonPushed, true);
            app.SaveFPTableButton.Text = 'Save FP Table';
            app.ClearFPTableButton = uibutton(app.FalsePositivesPanel, 'push');
            app.ClearFPTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFPTableButtonPushed, true);
            app.ClearFPTableButton.Text = 'Clear FP Table';
            app.LoadFPTableButton = uibutton(app.FalsePositivesPanel, 'push');
            app.LoadFPTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFPTableButtonPushed, true);
            app.LoadFPTableButton.Text = 'Load FP Table';
            app.PlottingTab = uitab(app.TabGroup2);
            app.PlottingTab.Title = 'Plotting';
            app.UIAxes = uiaxes(app.PlottingTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OpenCurrentPlottoFigureButton = uibutton(app.PlottingTab, 'push');
            app.OpenCurrentPlottoFigureButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCurrentPlottoFigureButtonPushed, true);
            app.OpenCurrentPlottoFigureButton.Enable = 'off';
            app.OpenCurrentPlottoFigureButton.Text = 'Open Current Plot to Figure';
            app.FirstCreatePanel = uipanel(app.PlottingTab);
            app.FirstCreatePanel.Title = 'First, Create';
            app.CreateDistributionsButton = uibutton(app.FirstCreatePanel, 'push');
            app.CreateDistributionsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDistributionsButtonPushed, true);
            app.CreateDistributionsButton.Text = 'Create Distributions';
            app.ThenPlotPanel = uipanel(app.PlottingTab);
            app.ThenPlotPanel.Title = 'Then, Plot';
            app.CLDistributionPlotButton = uibutton(app.ThenPlotPanel, 'push');
            app.CLDistributionPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CLDistributionPlotButtonPushed, true);
            app.CLDistributionPlotButton.Enable = 'off';
            app.CLDistributionPlotButton.Text = 'CL Distribution Plot';
            app.GenerateDetectionPercentagePlotButton = uibutton(app.ThenPlotPanel, 'push');
            app.GenerateDetectionPercentagePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDetectionPercentagePlotButtonPushed, true);
            app.GenerateDetectionPercentagePlotButton.Enable = 'off';
            app.GenerateDetectionPercentagePlotButton.Text = 'Generate Detection Percentage Plot';
            app.GenerateDetectionNumberPlotButton = uibutton(app.ThenPlotPanel, 'push');
            app.GenerateDetectionNumberPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDetectionNumberPlotButtonPushed, true);
            app.GenerateDetectionNumberPlotButton.Enable = 'off';
            app.GenerateDetectionNumberPlotButton.Text = 'Generate Detection Number Plot';
            app.SliderOnPlotButtonGroup = uibuttongroup(app.PlottingTab);
            app.SliderOnPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SliderOnPlotButtonGroupSelectionChanged, true);
            app.SliderOnPlotButtonGroup.Enable = 'off';
            app.SliderOnPlotButtonGroup.Title = 'Slider On Plot';
            app.YesButton = uiradiobutton(app.SliderOnPlotButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.SliderOnPlotButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.CLThresholdSlider_2Label = uilabel(app.PlottingTab);
            app.CLThresholdSlider_2Label.Text = {'CL '; 'Threshold'};
            app.CLThresholdSlider_2 = uislider(app.PlottingTab);
            app.CLThresholdSlider_2.Limits = [0 1];
            app.CLThresholdSlider_2.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CLThresholdSlider_2.ValueChangingFcn = createCallbackFcn(app, @CLThresholdSlider_2ValueChanging, true);
            app.CLThresholdSlider_2.Enable = 'off';
            app.ThisenablesTheslidertotherightmovetoupdateplotLabel = uilabel(app.PlottingTab);
            app.ThisenablesTheslidertotherightmovetoupdateplotLabel.Text = 'This enables The slider to the right, move to update plot.';
            app.ExportModelTab = uitab(app.TabGroup);
            app.ExportModelTab.Title = 'Export Model';
            app.TabGroup8 = uitabgroup(app.ExportModelTab);
            app.LocalTab_4 = uitab(app.TabGroup8);
            app.LocalTab_4.Title = 'Local';
            app.GetWeightsButton = uibutton(app.LocalTab_4, 'push');
            app.GetWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @GetWeightsFilepathButtonPushed, true);
            app.GetWeightsButton.Text = 'Get Weights!';
            app.TabGroup3 = uitabgroup(app.LocalTab_4);
            app.ManualTab = uitab(app.TabGroup3);
            app.ManualTab.Title = 'Manual';
            app.OpenPowershellButton_3 = uibutton(app.ManualTab, 'push');
            app.OpenPowershellButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenPowershellButton_3Pushed, true);
            app.OpenPowershellButton_3.Text = 'Open Powershell!';
            app.GenerateCodeButton_3 = uibutton(app.ManualTab, 'push');
            app.GenerateCodeButton_3.ButtonPushedFcn = createCallbackFcn(app, @GenerateCodeButton_3Pushed, true);
            app.GenerateCodeButton_3.Text = 'Generate Code!';
            app.CodePasteInEditFieldLabel = uilabel(app.ManualTab);
            app.CodePasteInEditFieldLabel.Text = 'Code Paste In';
            app.CodePasteInEditField = uieditfield(app.ManualTab, 'text');
            app.AutomaticTab = uitab(app.TabGroup3);
            app.AutomaticTab.Title = 'Automatic';
            app.ExportModelButton = uibutton(app.AutomaticTab, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Text = 'Export Model!';
            app.DockerTab_8 = uitab(app.TabGroup3);
            app.DockerTab_8.Title = 'Docker';
            app.aIfalreadyexistingContainerPanel_5 = uipanel(app.DockerTab_8);
            app.aIfalreadyexistingContainerPanel_5.Title = '1a. If already existing Container';
            app.ContainerIDEditField_5Label = uilabel(app.aIfalreadyexistingContainerPanel_5);
            app.ContainerIDEditField_5Label.Text = 'Container ID';
            app.ContainerIDEditField_5 = uieditfield(app.aIfalreadyexistingContainerPanel_5, 'text');
            app.OpenDockerContainerandExportPanel = uipanel(app.DockerTab_8);
            app.OpenDockerContainerandExportPanel.Title = '2. Open Docker Container and Export';
            app.DockerExportButton = uibutton(app.OpenDockerContainerandExportPanel, 'push');
            app.DockerExportButton.ButtonPushedFcn = createCallbackFcn(app, @DockerExportButtonPushed, true);
            app.DockerExportButton.Text = 'Docker Export';
            app.OpenPowershellButton_9 = uibutton(app.OpenDockerContainerandExportPanel, 'push');
            app.OpenPowershellButton_9.ButtonPushedFcn = createCallbackFcn(app, @OpenPowershellButtonPushed, true);
            app.OpenPowershellButton_9.Text = 'Open Powershell';
            app.ExecutionStep2Panel_5 = uipanel(app.DockerTab_8);
            app.ExecutionStep2Panel_5.Title = 'Execution Step 2';
            app.DockerExportCodeEditFieldLabel = uilabel(app.ExecutionStep2Panel_5);
            app.DockerExportCodeEditFieldLabel.Text = 'Docker Export Code';
            app.DockerExportCodeEditField = uieditfield(app.ExecutionStep2Panel_5, 'text');
            app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_3 = uipanel(app.DockerTab_8);
            app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_3.Title = 'Execution Step 1 (ONLY DO IF DOCKER CONTAINER NOT ALREADY RUNNING))';
            app.DockerCreateEnterEditField_5Label = uilabel(app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_3);
            app.DockerCreateEnterEditField_5Label.Text = 'Docker Create/Enter';
            app.DockerCreateEnterEditField_5 = uieditfield(app.ExecutionStep1ONLYDOIFDOCKERCONTAINERNOTALREADYRUNNINGPanel_3, 'text');
            app.RightMousetoCopyPasteintoDockerShellsLabel_5 = uilabel(app.DockerTab_8);
            app.RightMousetoCopyPasteintoDockerShellsLabel_5.Text = 'Right Mouse to Copy/Paste into Docker Shells';
            app.WeightsFilepathEditFieldLabel = uilabel(app.LocalTab_4);
            app.WeightsFilepathEditFieldLabel.Text = 'Weights Filepath';
            app.WeightsFilepathEditField = uieditfield(app.LocalTab_4, 'text');
            app.ImageSizeEditField_3Label = uilabel(app.LocalTab_4);
            app.ImageSizeEditField_3Label.Text = 'Image Size';
            app.ImageSizeEditField_3 = uieditfield(app.LocalTab_4, 'numeric');
            app.ImageSizeEditField_3.Limits = [1 Inf];
            app.ImageSizeEditField_3.RoundFractionalValues = 'on';
            app.ImageSizeEditField_3.Value = 1;
            app.NicksYoloV5TrainingPipelineAppV5DockerLabel = uilabel(app.UIFigure);
            app.NicksYoloV5TrainingPipelineAppV5DockerLabel.Text = 'Nick''s Yolo V5 Training Pipeline App V5 -Docker';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObjectDetectionPipelineV5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end